classdef ICP_Base < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BaseMenu                        matlab.ui.container.Menu
        OpenBaseMenu                    matlab.ui.container.Menu
        SaveBaseMenu                    matlab.ui.container.Menu
        SaveBaseasMenu                  matlab.ui.container.Menu
        ProjectMenu                     matlab.ui.container.Menu
        NewProjectMenu                  matlab.ui.container.Menu
        OpenProjectMenu                 matlab.ui.container.Menu
        SaveProjectMenu                 matlab.ui.container.Menu
        SaveProjectasMenu               matlab.ui.container.Menu
        InfosMenu                       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        SelectDirButton                 matlab.ui.control.Button
        WorkingDirectoryEditField       matlab.ui.control.EditField
        WorkingDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsButton                   matlab.ui.control.Button
        QuantificationButton            matlab.ui.control.Button
        DriftButton                     matlab.ui.control.Button
        ClassificationButton            matlab.ui.control.Button
        DeleteProjectButton             matlab.ui.control.Button
        AddProjectButton                matlab.ui.control.Button
        ProjectTable                    matlab.ui.control.Table
        IntervalButton                  matlab.ui.control.Button
        DeleteSessionButton             matlab.ui.control.Button
        SessionTable                    matlab.ui.control.Table
        AddSessionButton                matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        base
    end

    properties (Access = private)

        LastSelectedProjectRow
        CurrentReleaseTag = 'GitHubTag' % Description
        installpath % Description
    end


    methods (Access = private)

        function update_base_GUI(app, project_id)

            update_project_table(app)
            update_session_table(app, project_id)

        end

        function update_project_table(app)

            % preallocate the columns
            project_name=cell(1,numel(app.base));
            %project_comment=cell(1,numel(app.base));

            % get the session names and other variables
            for i = 1:numel(app.base)
                % since the project name is now initialzed at
                % AddSessionButtonDown it will always have 'New Project'
                if isfield(app.base(i), 'project_name') && ~isempty(app.base(i).project_name)

                    project_name{i} = app.base(i).project_name;

                else
                    project_name{i}='New Project';
                end

            end

            % generate a table with the variables
            T=table((1:numel(project_name))', project_name');
            T.Properties.VariableNames={'#', 'Project Name'};

            %push this table to the displayed SessionTable and give them
            %the same column names
            app.ProjectTable.Data = T;
            app.ProjectTable.ColumnName = T.Properties.VariableNames;

        end

        % Att: this function should just be GUI stuff and it should do
        % nothing or as little as possible with the actual data structure
        function update_session_table(app, project_id)

            % preallocate the columns
            session_name=cell(1,numel(app.base(project_id).project));
            session_comment=cell(1,numel(app.base(project_id).project));
            
            % get the session names and other variables
            for i = 1:numel(app.base(project_id).project)

                session_name{i} = app.base(project_id).project(i).session.session_name;

                  if isfield(app.base(project_id).project(i).session, 'comment') && ~isempty(app.base(project_id).project(i).session.comment)
                      session_comment{i} = app.base(project_id).project(i).session.comment;
                  else
                       session_comment{i}='';
                  end
            end

            % generate a table with the variables
            T=table((1:numel(session_name))', session_name', session_comment');
            T.Properties.VariableNames={'#', 'Session Name', 'Comment'};

            %push this table to the displayed SessionTable and give them
            %the same column names
            app.SessionTable.Data = T;
            app.SessionTable.ColumnName = T.Properties.VariableNames;

        end

        function update_progress_buttons(app, project_id, session_id)

            status = app.base(project_id).project(session_id).session.meta.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];


            app.ImportButton.Visible = "on";
            app.IntervalButton.Visible = "on";
            app.ClassificationButton.Visible = "on";
            app.DriftButton.Visible = "on";
            app.QuantificationButton.Visible = "on";
            app.ResultsButton.Visible = "on";

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'import') && status.import == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end
        end


        function check_for_update_fun(app,state)
            if not(exist("state","var"))
                state=false;
            end
            try
                % Define the owner and repo for your GitHub repository

                owner = 'GeoSebastianStumpf';
                repo = 'ICP-Base';
                url = sprintf('https://api.github.com/repos/%s/%s/releases', owner, repo);

                % Make the GET request to the GitHub API without authentication
                releaseData = webread(url);

                % If you want to extract specific fields, such as the tag name:
                if ~isempty(releaseData)
                    latestReleaseTag = releaseData(1).tag_name; % The first release in the array (latest)
                    disp(['Latest release tag: ' latestReleaseTag]);

                    if not(strcmp(latestReleaseTag,app.CurrentReleaseTag))

                        message = sprintf(['A new version of ICP-Base is available: <b>%s</b><br><br>'...
                            'You can download it from the following link: <a href="%s">https://github.com/GeoSebastianStumpf/ICP-Base/releases/latest</a>'], ...
                            latestReleaseTag, 'https://github.com/GeoSebastianStumpf/ICP-Base/releases/latest');

                        uialert(app.UIFigure,message,'New Version Available','Interpreter','html','Icon','info');
                    elseif state==true
                        message = sprintf('You have the latest version of ICP-Base installed: <b>%s</b><br><br>' , ...
                            latestReleaseTag);
                        uialert(app.UIFigure,message,'Latest Version Installed','Interpreter','html','Icon','success');

                    end

                else
                    disp('No releases found.');
                end
            catch ME
                if contains(ME.message, 'timeout') || contains(ME.message, 'Unable to resolve host') || contains(ME.message, 'Could not access server. https://api.github.com/repos/GeoSebastianStumpf/ICP-Base/releases.')
                    disp('New Version Check Failed - Unable to connect to GitHub. Please check your internet connection and try again.');
                elseif contains(ME.message, '404')
                    disp('New Version Check Failed - Repository not found. Please check the repository details.');
                else
                    DisplayError(ME,'Startup New Version Check Failed')
                end

            end
        end
    end


    methods (Access = public)

        % this function needs to sort everything back in the base 
        function [project_id, session_id] = save_to_base(app, project_id, session, session_id, project_name, settings)
            try
                %% sort in the session using the ids
                %% if the id is a number higher than 1, it will just go to that place

                % if I open the import module from the base, this is not needed
                % because the project_id is sent to the Import_Module
                % if the user decides to input 0 for all the project_ids
                if isequal(project_id, 0) || isempty(project_id)
                    project_id = numel(app.base) + 1;
                end

                % make an empty struct if a new project is created throught the
                % API
                if project_id > numel(app.base)
                    app.base(project_id).project = struct();
                end

                % these are for sorting the sessions back into the right projects
                if numel(app.base) == 0 % completely new base initialzed by API
                    session_id = 1;
                elseif isempty(fieldnames(app.base(project_id).project)) || ~isfield(app.base(project_id).project, 'session') || isempty(app.base(project_id).project)
                    session_id = 1;
                elseif isequal(session_id, 0) || isempty(session_id)
                    session_id = numel(app.base(project_id).project) + 1;
                end

                % save the session at the right place in the base
                app.base(project_id).project(session_id).session = session;

                %% session and project names

                app.base(project_id).project_name = project_name;

                % check if there is a session_name already in the session
                % struct, if not name it New Session
                if ~isfield(app.base(project_id).project(session_id).session, 'session_name') || isempty(app.base(project_id).project(session_id).session.session_name)
                    app.base(project_id).project(session_id).session.session_name = 'New Session';
                end

                %% GUI update
                if isfield(settings, 'ICP_Base_GUI_update_state') && settings.ICP_Base_GUI_update_state == true
                    update_base_GUI(app, project_id)

                    update_progress_buttons(app, project_id, session_id)
                end
            catch ME
                DisplayError(ME,'Error - save_to_base')
            end
        end

        
        function get_plotstyles(app,folderPath)
            % Define the folder path
        
       %    folderPath = "C:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\ICP-Base\ICP-Base_dev\Extras\Signal Plot Settings";
            csvFiles = dir(fullfile(folderPath, '*.csv'));

            % Initialize plotstyles with Default field so that there is
            % something to plot at startup
            %app.base.project.session.plotstyles.Default.Analytes = app.base.project.session.analytes;
            analytes = app.base.project.session.analytes;
            numAnalytes = length(analytes);
            app.base.project.session.plotstyles.Default.Analytes = analytes;
            % Set default values
            % Generate a color scheme using MATLAB's default color order
            colors = lines(numAnalytes);
            if isequal(app.base.project.session.meta.reductiontype, 'Matrix')
                % Set default values
                app.base.project.session.plotstyles.Default.Color = arrayfun(@(i) colors(i, :), 1:numAnalytes, 'UniformOutput', false);
                app.base.project.session.plotstyles.Default.LineWidth = repmat(1.5, numAnalytes, 1);
                app.base.project.session.plotstyles.Default.Linestyle = repmat({'-'}, numAnalytes, 1); % Solid line
                app.base.project.session.plotstyles.Default.Marker = repmat({'none'}, numAnalytes, 1);
                app.base.project.session.plotstyles.Default.MarkerSize = repmat({'none'}, numAnalytes, 1);
                app.base.project.session.plotstyles.Default.MarkerFaceColor = repmat({'none'}, numAnalytes, 1);
                app.base.project.session.plotstyles.Default.MarkerEdgeColor = repmat({'none'}, numAnalytes, 1);
            elseif isequal(app.base.project.session.meta.reductiontype, 'Inclusion')
                app.base.project.session.plotstyles.Default.Color = arrayfun(@(i) colors(i, :), 1:numAnalytes, 'UniformOutput', false);
                app.base.project.session.plotstyles.Default.LineWidth = repmat(1.5, numAnalytes, 1);
                app.base.project.session.plotstyles.Default.Linestyle = repmat({'-'}, numAnalytes, 1); % Solid line
                app.base.project.session.plotstyles.Default.Marker = repmat({'.'}, numAnalytes, 1);
                app.base.project.session.plotstyles.Default.MarkerSize = repmat(1, numAnalytes, 1);
                app.base.project.session.plotstyles.Default.MarkerFaceColor = repmat({'black'}, numAnalytes, 1);
                app.base.project.session.plotstyles.Default.MarkerEdgeColor = repmat({'black'}, numAnalytes, 1);
            end

            % Loop through each CSV file in the folder
            for k = 1:length(csvFiles)
                baseFileName = csvFiles(k).name;
                if strcmp(baseFileName, 'Template.csv')
                    continue; % Skip the Template.csv file
                end
                fullFileName = fullfile(folderPath, baseFileName);
                try
                    data = readtable(fullFileName);

                    data = data(~any(ismissing(data), 2), :);

                    dataStruct = table2struct(data, 'ToScalar', true);
                    [~, name, ~] = fileparts(baseFileName);

                    app.base.project.session.plotstyles.(name) = dataStruct;

                catch ME
                    warning('Failed to read or process file: %s\nError: %s', fullFileName, ME.message);
                end
            end

            % Create a table for Template.csv with empty columns except Analytes
            defaultTable = table(analytes, repmat({''}, numAnalytes, 1), repmat({''}, numAnalytes, 1), ...
                repmat({''}, numAnalytes, 1), repmat({''}, numAnalytes, 1), ...
                repmat({''}, numAnalytes, 1), repmat({''}, numAnalytes, 1), ...
                repmat({''}, numAnalytes, 1), 'VariableNames', ...
                {'Analytes', 'Color', 'LineWidth', 'Linestyle', 'Marker', 'MarkerSize', 'MarkerFaceColor', 'MarkerEdgeColor'});

            % Write the table to Template.csv
            defaultFileName = fullfile(folderPath, 'Template.csv');
            writetable(defaultTable, defaultFileName);

        end
        
function get_RM_Files(app,folderPath)
          %  folderPath = "C:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\ICP-Base\ICP-Base_dev\Extras\SRM Files";
            RM_files = dir(fullfile(folderPath, '*.csv'));

            filenames = {};
            allTables = {};

            for i = 1:length(RM_files)
                % Get the full path of the file
                filePath = fullfile(folderPath, RM_files(i).name);

                % Read the contents of the file
                [raw] = readtable(filePath);

                % Extract elements and mass fractions
                elements = raw{:, 1};
                massfractions = table2array(raw(:, 2)); % Convert cell array to numeric array

                % Create a table with elements as column headers and mass fractions as the first row
                dataTable = array2table(massfractions', 'VariableNames', elements');

                % Extract the filename without extension
                filename = RM_files(i).name;
                filename = filename(1:end-4);

                % Append to the lists
                filenames{end+1} = filename;
                allTables{end+1} = dataTable;
            end

            % Assign to the app structure
            app.base.project.session.referencematerials.names = filenames';
            app.base.project.session.referencematerials.data = allTables';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
if isdeployed %standalone mode.
    try
        if ispc
            [~, result] = system('path');
            app.installpath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
        elseif ismac
            path=ctfroot;
            disp('ctfroot:')
            disp(path)

            path_split=split(path,{'ICP-Base.app'});
            disp('path_split')
            disp(path_split)

            app.installpath=path_split{1};
            disp('Installation Directory 0:')
            disp(app.installpath)
            disp('')

            if not(exist(fullfile(app.installpath,'SRM_Files','NIST610.csv'),'file'))
                answer = uiconfirm(app.UIFigure,{'Application directory not found - Select ICP-Base directory'},'Select directory','Options',"OK",'Icon','warning');
                app.installpath=uigetdir(pwd,'select application directory');
            end
        elseif isunix
            try
                app.installpath=pwd;
            catch
                answer = uiconfirm(app.UIFigure,{'Application directory not found - Select ICP-Base directory'},'Select directory','Options',"OK",'Icon','warning');
                app.installpath=uigetdir(pwd,'select application directory');

            end
        end

        tmp_path='';

        if exist(fullfile(app.installpath,'Dev','ICP-BaseWorkingDir'),'file')
            tmp_path= fileread(fullfile(app.installpath,'Dev','ICP-BaseWorkingDir'));
            disp('tmp_path 0:')
            disp(tmp_path)
            if exist(tmp_path,'dir')
                try
                    diary(fullfile(tmp_path,'ICP-Base_log.txt'))
                    user_path=tmp_path;
                catch
                    disp('could not use user_path to create logfile')
                    tmp_path='There was an error logfile could not be created';
                end
            end
        end

        if isempty(tmp_path) || not(exist(tmp_path,'dir'))

            user_path=userpath;
            disp('Userpath -2:')
            disp(user_path)

            user_path=replace(user_path,'/MATLAB','');
            user_path=replace(user_path,'\MATLAB','');
            disp('Userpath -1:')
            disp(user_path)
            user_path=fullfile(user_path,'ICP-Base');

            disp('Userpath 0:')
            disp(user_path)


            if ~exist(user_path,'dir')
                try
                    disp('Userpath does not exist')
                    [status, msg, ~] = mkdir(user_path);
                    if status==0
                        answer = uiconfirm(app.UIFigure,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');

                        disp('Userpath folder could not be created:')
                        disp(msg)
                        f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                        user_path=uigetdir(pwd,'select working directory with read/write permission');
                        delete(f)
                        %   disp('Userpath folder could not be created')
                    else
                        disp('Userpath folder created:')
                        disp(user_path)
                    end

                catch ME
                    DisplayError(ME,'Startup Error mkdir')

                    if ~exist(user_path,'dir')
                        answer = uiconfirm(app.UIFigure,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                        % uialert(app.UIFigure,'To avoide this message create a folder called ICP-Base in your Documents folder',' Error');
                        user_path=uigetdir(pwd,'select working directory with read/write permission');
                    end
                end
            end
        end

        diary(fullfile(user_path,'ICP-Base_log.txt'));

        disp('Userpath 1:')
        disp(user_path)

        disp('Installation Directory:')
        disp(app.installpath)
        disp('')

        disp('tmp_path 1:')
        disp(tmp_path)
        disp('')


        app.WorkingDirectoryEditField.Value=user_path;
        app.WorkingDirectoryEditField.Tooltip=user_path;

        cd(user_path);
        if not(exist(fullfile(app.installpath,'Dev','ICP-BaseWorkingDir'),'file')) || isempty(tmp_path)
            try
                dir2exp=fullfile(app.installpath,'Dev','ICP-BaseWorkingDir');
                disp('dir2exp:')
                disp(dir2exp)

                [fileID,errmsg] = fopen(dir2exp,'w');
                if fileID==-1
                    dir2exp2=fullfile(user_path,'ICP-BaseWorkingDir');
                    [fileID,errmsg] = fopen(dir2exp2,'w');
                    fprintf(fileID,'%s',user_path);
                    fclose(fileID);
                    destination=fullfile(app.installpath,'Dev');
                    [status,msg,~]= movefile(dir2exp2, destination,'f');
                    if status==false
                        disp('Error msg:')
                        disp(msg)
                    end
                else
                    fprintf(fileID,'%s',user_path);
                    fclose(fileID);
                end
            catch ME
                disp('errmsg:')
                disp(errmsg)

                DisplayError(ME,'Startup Error ICP-BaseWorkingDir file')

                ans1= uiconfirm(app.UIFigure, ...
                    {'ICP-BaseWorkingDir file - Permission Error!',...
                    'To fix this Error you have to options:', '-'...
                    '1) Execute the following command in the terminal:',...
                    [ 'chmod -R 755 ' app.installpath ],'-',...
                    '2) Manually add a path to your default working directory folder with read/write permission to the file:'...
                    , fullfile(app.installpath,'Dev','ICP-BaseWorkingDir') },"Permission Error", ...
                    "Icon","warning");
                disp(ans1)

            end
        end
    catch ME
        DisplayError(ME,'Startup Error 1')
    end

else
    user_path=fileparts(which('ICP-Base.mlapp'));
    app.installpath=user_path;
    app.WorkingDirectoryEditField.Value=pwd;
    app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;

end


%% check for new version
% check_for_update_fun(app)


%% Achtung Anpassen
%% implement read plot_styles and SRM files here

try
    folderPath=fullfile(app.installpath,'SRM_Files');
get_RM_Files(app,folderPath)

catch ME
    DisplayError(ME,'Error reading MineralCalcFunctions.csv')
    message={'Error reading MineralCalcFunctions.csv'};
    uialert(app.UIFigure,message,' Error');
end

% read plot_styles 
try

    folderPath=fullfile(app.installpath,'Plot_Styles');
    get_plotstyles(app,folderPath)
catch  ME
    DisplayError(ME,'Error reading PlotStyles')
    message={'Error reading Colormaps'};
    uialert(app.UIFigure,message,' Error');
end


        end

        % Button pushed function: AddSessionButton
        function AddSessionButtonPushed(app, event)
            % Att: this button should be disabled as long as there is no
            % project

            %% get the project_id as the selection of the ProjectTable

            project_id = app.LastSelectedProjectRow;

            if isempty(project_id)
                message = "Select a Project first";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end

            %% here the session id is assigned to the new session and then sent to the Import_Module
            % if the field session in DIQES does not yet exist, the first
            % session has id zero. when it is sent back it will change to 1
            % if not(isfield(app.base(project_id).project, 'session'))
            % 
            %     session_id = 0;
            % 
            % else % the sid is number of sessions + 1 when other sessions are added
            % 
            %     session_id = numel(app.base(project_id).project) + 1;
            % 
            % end
            % 
            % since a new session is added, there is no need to send the
            % whole project, just the ids are sent to the
            % Import_Module


            session_id = 0;
            Import_Module(app, app.base(project_id).project_name, project_id, session_id)
            % app = der Handle von DIQES,
            % was app hier ist, ist sourceapp in Import_Module

        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ImportButton, IntervalButton, QuantificationButton, ResultsButton
        function ImportButtonPushed(app, event)

            % check if selection is not empty
            if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                % session_id is the selection
                session_id = app.SessionTable.Selection(1);
                % the project_id is the last selected project in the
                % project table
                project_id = app.LastSelectedProjectRow;

                switch event.Source.Parent.Parent.CallbackObject.Text
                    case 'Show Session in Import Module'
                        Import_Module(app, app.base(project_id).project_name, project_id, session_id)
                    case 'Show Session in Interval Module'
                        Interval_Module(app, app.base(project_id).project_name, project_id, session_id)
                    case 'Show Session in Classification Module'
                        Classification_Module(app, app.base(project_id).project_name, project_id, session_id)
                    case 'Show Session in Drift Module'
                        Drift_Module(app, app.base(project_id).project_name, project_id, session_id)
                    case 'Show Session in Quantification Module'
                        Quantification_Module(app, app.base(project_id).project_name, project_id, session_id)
                    case 'Show Session in Results Module'
                        Results_Module(app, app.base(project_id).project_name, project_id, session_id)
                end

            end


        end

        % Cell edit callback: SessionTable
        function SessionTableCellEdit(app, event)
          
            % get the session table selection
            sel_row_session = app.SessionTable.Selection(1);
            sel_col_session = app.SessionTable.Selection(2);

            % this takes the LastSelectedProjectRow
            if strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Session Name')
                 app.base(app.LastSelectedProjectRow).project(sel_row_session).session.session_name = event.NewData;
            elseif strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Comment')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.comment = event.NewData;
            end

        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            %% this also needs the project_id to know which project to save

            % if isprop(app,'projectpath') && not(isempty(app.base.projectpath))
            %     app.UIFigure.Name = [app.base.projectpath ' [... Saving Project]'] ;
            % 
            %     data2save.project = app.base.project;
            %     data2save.projectpath = app.base.projectpath;
            % 
            %     save(app.base.projectpath, 'data2save', '-v7.3');
            %     app.UIFigure.Name = app.base.projectpath;
            % 
            % else
            %     SaveProjectasMenuSelected(app)
            % end
            % 
            % if isprop(app,'projectpath')
            % 
            %     app.UIFigure.Name = app.base.projectpath;
            % 
            % end
        end

        % Menu selected function: SaveProjectasMenu
        function SaveProjectasMenuSelected(app, event)


            % f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            % [filename, filepath] = uiputfile('*.mat', 'Save the project file:');
            % delete(f);
            % figure(app.UIFigure);
            % 
            % if not(isequal(filename,0))
            %     FileName = fullfile(filepath, filename);
            %     app.base.projectpath = FileName;
            %     app.UIFigure.Name = [app.base.projectpath ' [... Saving Project]'] ;
            % 
            %     data2save.project = app.base.project;
            %     data2save.projectpath = app.base.projectpath;
            % 
            %     save(FileName, 'data2save', '-v7.3');
            %     app.UIFigure.Name = app.base.projectpath;
            % end
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)

            %     if isfield(app.base,'projectpath') && not(isempty(app.base.projectpath))
            %         [~, baseFileNameNoExt, ~] = fileparts(app.base.projectpath);
            %         projectname=[' ' baseFileNameNoExt];
            % 
            %     else
            %         projectname='';
            %     end
            %     answer = questdlg({['Do you want to save your project' projectname '?']},...
            %         '','Yes','No','Cancel','');
            %     switch answer
            %         case 'Yes'
            %             SaveProjectMenuSelected(app)
            %         case 'Cancel'
            %             return
            %     end
            % 
            % 
            % f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            % [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Project');
            % delete(f);
            % figure(app.UIFigure);
            % 
            % if not(isequal(file,0))
            %     data2import = load(fullfile(path,file));
            % 
            %     app.base.project = data2import.data2save.project;
            %     app.base.projectpath = data2import.data2save.projectpath;
            % else
            %     return
            % end
            % 
            % update_session_table(app)


        end

        % Button pushed function: AddProjectButton
        function AddProjectButtonPushed(app, event)
            %% initializing empty projects in the base
            if not(isfield(app.base, 'project'))

                app.base(1).project = struct();
                app.base(1).project_name = 'New Project';

            else % the entry_id

                entry_id = numel(app.base) + 1;

                app.base(entry_id).project = struct();
                app.base(entry_id).project_name = 'New Project';

            end

            update_project_table(app)
        end

        % Callback function: ProjectTable
        function ProjectTableSelectionChanged(app, event)
            %% updates the session table when a project is selected
            if ~isempty(app.ProjectTable.Selection)
                if not(isempty(app.ProjectTable.Selection(1)))
                    selection = app.ProjectTable.Selection(1);
                else
                    return
                end

                if not(isempty(fieldnames(app.base(selection).project))) && isfield(app.base(selection).project, 'session')
                    update_session_table(app, selection)
                else
                    app.SessionTable.Data = {};
                end
            else
                return
            end

            %% failsafe if a project is clicked on but then it is clicked 
            % somewhere in the ProjectTable where there is no project, the
            % selection gets set to [] and the conncection to the project
            % is lost
            % so the last clicked on row is always saved to always get back
            % to the project
            % Check if there is a selection in the ProjectTable
            if isempty(app.ProjectTable.Selection)
                % Use the last stored selection if the current selection is empty
                app.LastSelectedProjectRow = app.LastSelectedProjectRow;
            else
                % Update the last stored selection with the current selection
                app.LastSelectedProjectRow = app.ProjectTable.Selection(1);
            end

            % if Tableselection changed make all buttons just grey

            app.ImportButton.Visible = "off";
            app.IntervalButton.Visible = "off";
            app.ClassificationButton.Visible = "off";
            app.DriftButton.Visible = "off";
            app.QuantificationButton.Visible = "off";
            app.ResultsButton.Visible = "off";

        end

        % Menu selected function: SaveBaseMenu
        function SaveBaseMenuSelected(app, event)

            if isfield(app.base, 'meta') && isfield(app.base(1).meta, 'basepath') && not(isempty(app.base(1).meta.basepath))
                app.UIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;
                %data2save.meta = app.meta;

                save(app.base(1).meta.basepath, 'data2save', '-v7.3');
                app.UIFigure.Name = app.base(1).meta.basepath;

            else
                SaveBaseasMenuSelected(app)
            end

            if isfield(app.base,'meta') && isfield(app.base(1).meta, 'basepath')

                app.UIFigure.Name = app.base(1).meta.basepath;

            end
        end

        % Menu selected function: OpenBaseMenu
        function OpenBaseMenuSelected(app, event)
            
                if isfield(app.base,'meta') && isfield(app.base(1).meta, 'basepath') && not(isempty(app.base(1).meta.basepath))
                    [~, baseFileNameNoExt, ~] = fileparts(app.base(1).meta.basepath);
                    basename=[' ' baseFileNameNoExt];

                else
                    basename='';
                end
                answer = questdlg({['Do you want to save this base' basename '?']},...
                    '','Yes','No','Cancel','');
                switch answer
                    case 'Yes'
                        SaveBaseMenuSelected(app)
                    case 'Cancel'
                        return
                end


            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Base');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(file,0))
                data2import = load(fullfile(path,file));

                app.base = data2import.data2save.base;
            else
                return
            end

            update_project_table(app)

        end

        % Cell edit callback: ProjectTable
        function ProjectTableCellEdit(app, event)
            % get the session table selection
            sel_row_project = app.ProjectTable.Selection(1);
            sel_col_project = app.ProjectTable.Selection(2);


            if strcmp(app.ProjectTable.ColumnName{sel_col_project}, 'Project Name')
                 app.base(sel_row_project).project_name = event.NewData;
            end
        end

        % Menu selected function: SaveBaseasMenu
        function SaveBaseasMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.mat', 'Save the base file:');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(filename,0))
                FileName = fullfile(filepath, filename);
                app.base(1).meta.basepath = FileName;
                app.UIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;

                save(FileName, 'data2save', '-v7.3');
                app.UIFigure.Name = app.base(1).meta.basepath;
            end
        end

        % Button pushed function: DeleteProjectButton
        function DeleteProjectButtonPushed(app, event)
            if ~isempty(app.ProjectTable.Selection)
                %% deleting selected project
                selection = app.ProjectTable.Selection(1);
                app.base(selection) = [];
            else
                return
            end

            update_project_table(app)
        end

        % Callback function: SessionTable
        function SessionTableSelectionChanged(app, event)
            % session_id is the selection

            if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                session_id = app.SessionTable.Selection(1);
                % the project_id is the last selected project in the
                % project table
                project_id = app.LastSelectedProjectRow;
                update_progress_buttons(app, project_id, session_id)
            end
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
               f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir(pwd,'select export directory with read/write permission');
            delete(f)
            figure(app.UIFigure);

            if path==0
                app.WorkingDirectoryEditField.Value=pwd;
            else
                app.WorkingDirectoryEditField.Value=path;
            end
            app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;

            WorkingDirectoryEditFieldValueChanged(app)


        end

        % Value changed function: WorkingDirectoryEditField
        function WorkingDirectoryEditFieldValueChanged(app, event)
            value = app.WorkingDirectoryEditField.Value;
            if not(exist(value,'dir'))
                uiconfirm(app.UIFigure,{'Directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path=uigetdir(pwd,'select working directory with read/write permission');
                delete(f)
                figure(app.UIFigure);

                if ~isequal(path,0)
                    cd(path)
                    app.WorkingDirectoryEditField.Value=path;
                else
                    app.WorkingDirectoryEditField.Value=pwd;
                end
                app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;
                diary('ICP-Base_log.txt');
            else
                diary('ICP-Base_log.txt');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ICP-Base';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.OpenBaseMenu = uimenu(app.BaseMenu);
            app.OpenBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenBaseMenuSelected, true);
            app.OpenBaseMenu.Text = 'Open Base';
            app.SaveBaseMenu = uimenu(app.BaseMenu);
            app.SaveBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBaseMenuSelected, true);
            app.SaveBaseMenu.Text = 'Save Base';
            app.SaveBaseasMenu = uimenu(app.BaseMenu);
            app.SaveBaseasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBaseasMenuSelected, true);
            app.SaveBaseasMenu.Text = 'Save Base as';
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewProjectMenu = uimenu(app.ProjectMenu);
            app.NewProjectMenu.Text = 'New Project';
            app.OpenProjectMenu = uimenu(app.ProjectMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Text = 'Open Project';
            app.SaveProjectMenu = uimenu(app.ProjectMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.SaveProjectasMenu = uimenu(app.ProjectMenu);
            app.SaveProjectasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectasMenuSelected, true);
            app.SaveProjectasMenu.Text = 'Save Project as';
            app.InfosMenu = uimenu(app.UIFigure);
            app.InfosMenu.Text = 'Infos';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Show Session in Import Module';
            app.AddSessionButton = uibutton(app.GridLayout, 'push');
            app.AddSessionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSessionButtonPushed, true);
            app.AddSessionButton.Text = 'Add Session';
            app.SessionTable = uitable(app.GridLayout);
            app.SessionTable.ColumnName = '';
            app.SessionTable.RowName = {};
            app.SessionTable.ColumnSortable = [true true];
            app.SessionTable.ColumnEditable = [false true true];
            app.SessionTable.CellEditCallback = createCallbackFcn(app, @SessionTableCellEdit, true);
            app.SessionTable.SelectionChangedFcn = createCallbackFcn(app, @SessionTableSelectionChanged, true);
            app.SessionTable.Multiselect = 'off';
            app.DeleteSessionButton = uibutton(app.GridLayout, 'push');
            app.DeleteSessionButton.Text = 'Delete Session';
            app.IntervalButton = uibutton(app.GridLayout, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.IntervalButton.Text = 'Show Session in Interval Module';
            app.ProjectTable = uitable(app.GridLayout);
            app.ProjectTable.ColumnName = '';
            app.ProjectTable.RowName = {};
            app.ProjectTable.ColumnEditable = [false true];
            app.ProjectTable.CellEditCallback = createCallbackFcn(app, @ProjectTableCellEdit, true);
            app.ProjectTable.SelectionChangedFcn = createCallbackFcn(app, @ProjectTableSelectionChanged, true);
            app.ProjectTable.Multiselect = 'off';
            app.AddProjectButton = uibutton(app.GridLayout, 'push');
            app.AddProjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddProjectButtonPushed, true);
            app.AddProjectButton.Text = 'Add Project';
            app.DeleteProjectButton = uibutton(app.GridLayout, 'push');
            app.DeleteProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProjectButtonPushed, true);
            app.DeleteProjectButton.Text = 'Delete Project';
            app.ClassificationButton = uibutton(app.GridLayout, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ClassificationButton.Text = 'Show Session in Classification Module';
            app.DriftButton = uibutton(app.GridLayout, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.DriftButton.Text = 'Show Session in Drift Module';
            app.QuantificationButton = uibutton(app.GridLayout, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.QuantificationButton.Text = 'Show Session in Quantification Module';
            app.ResultsButton = uibutton(app.GridLayout, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ResultsButton.Text = 'Show Session in Results Module';
            app.WorkingDirectoryEditFieldLabel = uilabel(app.GridLayout);
            app.WorkingDirectoryEditFieldLabel.Text = 'Working Directory';
            app.WorkingDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.WorkingDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @WorkingDirectoryEditFieldValueChanged, true);
            app.SelectDirButton = uibutton(app.GridLayout, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Text = 'Select Dir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICP_Base

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end