classdef Import_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BaseMenu                       matlab.ui.container.Menu
        SaveSessiontoBaseandExitMenu   matlab.ui.container.Menu
        SessionMenu                    matlab.ui.container.Menu
        SaveSessionMenu                matlab.ui.container.Menu
        GridLayout2                    matlab.ui.container.GridLayout
        ProgressPanel                  matlab.ui.container.Panel
        ResultsButton                  matlab.ui.control.Button
        QuantificationButton           matlab.ui.control.Button
        DriftButton                    matlab.ui.control.Button
        ClassificationButton           matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        IntervalButton                 matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        MeasurementFilesLaserLogfileTab  matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        globalsignalindentEditField    matlab.ui.control.NumericEditField
        ProceedinMatrixModeButton      matlab.ui.control.Button
        LogScaleCheckBox               matlab.ui.control.CheckBox
        ShowSpotnamesCheckBox          matlab.ui.control.CheckBox
        ShowLogfileInformationsButton  matlab.ui.control.Button
        ShowDwellTimesButton           matlab.ui.control.Button
        DetectSignalsLamp              matlab.ui.control.Lamp
        PlotSignalRangesCheckBox       matlab.ui.control.CheckBox
        DetectSignalsButton            matlab.ui.control.Button
        MeasurementLamp                matlab.ui.control.Lamp
        xlimMax_p10                    matlab.ui.control.Button
        xlimMax_p1                     matlab.ui.control.Button
        EditField_xlimMax              matlab.ui.control.NumericEditField
        xlimMax_m1                     matlab.ui.control.Button
        xlimMax_m10                    matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        xlimMin_p10                    matlab.ui.control.Button
        xlimMin_p1                     matlab.ui.control.Button
        EditField_xlimMin              matlab.ui.control.NumericEditField
        xlimMin_m1                     matlab.ui.control.Button
        xlimMin_m10                    matlab.ui.control.Button
        ZoomedPlot_Legend              matlab.ui.control.ListBox
        DwellTimeLamp                  matlab.ui.control.Lamp
        LogfileLamp                    matlab.ui.control.Lamp
        ChooseDwellTimeDropDown        matlab.ui.control.DropDown
        GetDwellTimesButton            matlab.ui.control.Button
        DwellTimeEditField             matlab.ui.control.EditField
        ProceedinInclusionModeButton   matlab.ui.control.Button
        ChooseLaserDropDown            matlab.ui.control.DropDown
        ChooseMassSpecDropDown         matlab.ui.control.DropDown
        GetLogFileButton               matlab.ui.control.Button
        GetMeasurementFileButton       matlab.ui.control.Button
        LogFileEditField               matlab.ui.control.EditField
        MeasurementFileEditField       matlab.ui.control.EditField
        FigXlimLive                    matlab.ui.control.UIAxes
        ZoomedPlot                     matlab.ui.control.UIAxes
        SingleSignalsShotbyShotTab     matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        ProceedinInclusionModeButton_Single  matlab.ui.control.Button
        LogFileDropDown_Single         matlab.ui.control.DropDown
        GetLogFileButton_Single        matlab.ui.control.Button
        ProceedinMatrixModeButton_Single  matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        ChooseDwellTimeDropDown_2      matlab.ui.control.DropDown
        GetDwelltimesButton            matlab.ui.control.Button
        ChooseMassSpecSSDropDown       matlab.ui.control.DropDown
        SingleSpotsListBox             matlab.ui.control.ListBox
        GetSingleSpotFileButton        matlab.ui.control.Button
        SingleSpotAxes                 matlab.ui.control.UIAxes
        SILLSProjectTab                matlab.ui.container.Tab
        ImportSILLSprojectButton       matlab.ui.control.Button
        VisualSelectionTab             matlab.ui.container.Tab
    end


    properties (Access = public)

        %% General variables
        base
        project_id
        session_id


    end

    properties (Access = private)
        sourceapp

        %% Stuff for the interactive plots
        hVerticalLines
        hLineToDrag
        xlimits
        selected_analytes

        %% Variables for Single Signal Tab
        selected_spot

        %% 
        global_signal_indent

        %%
        editstatus
    end


    methods (Access = private)
        %% general functions

        function update_progress_buttons(app)

            status = app.base.project.session.meta.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            app.ImportButton.Enable = "off";
            app.ImportButton.BackgroundColor = orange; % orange because this is the active module

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end
        end

        function changes = check_changes(app)


            status = app.base.project.session.meta.module_status;

            if  status.drift || status.quantification

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "You changed something, do you want to reset everything?";
                title = "Test";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Yes","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                switch selection
                    case 'Yes'
                        app.base.project.session.meta.module_status.interval = false;
                        app.base.project.session.meta.module_status.classification = false;
                        app.base.project.session.meta.module_status.drift = false;
                        app.base.project.session.meta.module_status.quantification = false;
                        app.base.project.session.meta.module_status.results = false;


                        % delete all variables that depend on this from the
                        % delete all variables that depend on this

                        if isfield(app.base.project.session, 'plotstyles')
                            app.base.project.session = rmfield(app.base.project.session, 'plotstyles');
                        end

                        if isfield(app.base.project.session, 'referencematerials')
                            app.base.project.session = rmfield(app.base.project.session, 'referencematerials');
                        end

                        if isfield(app.base.project.session.spot, 'background_starts')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'background_starts');
                        end

                        if isfield(app.base.project.session.spot, 'background_ends')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'background_ends');
                        end

                        if isfield(app.base.project.session.spot, 'matrix_1_starts')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_1_starts');
                        end

                        if isfield(app.base.project.session.spot, 'matrix_1_ends')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_1_ends');
                        end

                        if isfield(app.base.project.session.spot, 'matrix_2_starts')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_2_starts');
                        end

                        if isfield(app.base.project.session.spot, 'matrix_2_ends')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_2_ends');
                        end

                        if isfield(app.base.project.session.spot, 'matrix_3_starts')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_3_starts');
                        end

                        if isfield(app.base.project.session.spot, 'matrix_3_ends')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_3_ends');
                        end

                        if isfield(app.base.project.session.spot, 'type')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'type');
                        end

                        if isfield(app.base.project.session.spot, 'referencematerial')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'referencematerial');
                        end

                        if isfield(app.base.project.session.spot, 'bracket_id')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'bracket_id');
                        end

                        if isfield(app.base.project.session.spot, 'integer_time_points')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'integer_time_points');
                        end

                        if isfield(app.base.project.session.spot, 'bkgd_corrected_cps')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'bkgd_corrected_cps');
                        end

                        if isfield(app.base.project.session.spot, 'sensitivities')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'sensitivities');
                        end
                        if isfield(app.base.project.session.spot, 'internal_standard')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard');
                        end
                        if isfield(app.base.project.session.spot, 'internal_standard_mass_fraction')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard_mass_fraction');
                        end
                        if isfield(app.base.project.session.spot, 'internal_standard_oxide_wt')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard_oxide_wt');
                        end
                        if isfield(app.base.project.session.spot, 'Fe_ratio_for_total_oxide')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'Fe_ratio_for_total_oxide');
                        end
                        if isfield(app.base.project.session.spot, 'matrix_composition')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_composition');
                        end
                        if isfield(app.base.project.session.spot, 'matrix_limits_of_detection')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_limits_of_detection');
                        end

                        if isequal(app.base.project.session.meta.reductiontype, 'Inclusion')

                            if isfield(app.base.project.session.spot, 'inclusion_1_starts')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'inclusion_1_starts');
                            end

                            if isfield(app.base.project.session.spot, 'inclusion_1_ends')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'inclusion_1_ends');
                            end

                            if isfield(app.base.project.session.spot, 'internal_standard_inclusion')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard_inclusion');
                            end
                            if isfield(app.base.project.session.spot, 'internal_standard_mass_fraction_inclusion')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard_mass_fraction_inclusion');
                            end
                            if isfield(app.base.project.session.spot, 'inclusion_composition')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'inclusion_composition');
                            end
                            if isfield(app.base.project.session.spot, 'inclusion_limits_of_detection')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'inclusion_limits_of_detection');
                            end

                        end

                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        changes = true;

                        update_progress_buttons(app)

                    case 'Cancel'
                        %update_gui_function maybe(app)
                        changes = false;
                        return
                end
            else
                changes = true;
            end
        end

        %%%%%%%%%%% Tab Measurement File + Laser Logfile %%%%%%%%%%

        function update_Imp_Mod_GUI_Meas_Log_Tab(app)

            %% Interactive plots
            % Initialize the correct xlims again for the FigXlimLive
            if isfield(app.base.project.session, 'ms_data')
                % get the minimum and maximum limits for the FigXlimLive Plot
                % xlimval=[min([app.EditField_xlimMin.Value; table2array(app.base.project.session.ms_data(:, 1))])...
                %     max([app.EditField_xlimMax.Value; table2array(app.base.project.session.ms_data(:, 1))])];
                % Here they get filled for the first time
                % app.EditField_xlimMax.Value = max(xlimval);
                % app.EditField_xlimMin.Value = min(xlimval);

                % Here they get filled for the first time
                app.EditField_xlimMax.Value = length(table2array(app.base.project.session.ms_data(:, 1)));
                app.EditField_xlimMin.Value = 1;

                xlimval = [app.EditField_xlimMin.Value, app.EditField_xlimMax.Value];

                app.xlimits = xlimval;

                % update the plots
                update_FigXlimLive(app)
                UpdateZoomedPlot(app)
                update_ZoomedPlot_Legend_fun(app)
            end

            %% Editfields and Buttons
            if isfield(app.base.project.session, 'ms_data')
                app.MeasurementFileEditField.Value = app.base.project.session.measurementpath;
                app.MeasurementLamp.Color = 'g';
            end

            if isfield(app.base.project.session, 'logfilepath')
                app.LogFileEditField.Value = app.base.project.session.logfilepath;
                app.LogfileLamp.Color = 'g';
                app.ShowLogfileInformationsButton.Enable = 'on';
            end

            if isfield(app.base.project.session, 'dwelltimepath')
                app.DwellTimeEditField.Value = app.base.project.session.dwelltimepath;
                app.DwellTimeLamp.Color = 'g';
                app.ShowDwellTimesButton.Enable = 'on';
            end

            if isfield(app.base.project.session, 'spot') && isfield(app.base.project.session.spot, 'signal_starts') && isfield(app.base.project.session.spot, 'signal_ends')
                app.DetectSignalsLamp.Color = 'g';
            end


            % if isfield(app.base.project.session.meta, 'reductiontype') && ~isempty(app.base.project.session.meta.reductiontype)
            %     switch app.base.project.session.meta.reductiontype
            %         case 'Matrix'
            %             app.ProceedinInclusionModeButton_Single.Enable = 'off';
            %             app.ProceedinInclusionModeButton = 'off';
            %         case 'Inclusion'
            %             app.ProceedinMatrixModeButton_Single.Enable = 'off';
            %             app.ProceedinMatrixModeButton = 'off';
            %     end
            % end

            update_progress_buttons(app)

        end

        % Plot Functions for the interactive plots

        function reset_zoom_pan(app)
            try
                zoom(app.ZoomedPlot,'off')
                pan(app.ZoomedPlot,'off')
            catch ME
                disp('Error - reset zoom / pan')
                disp(ME)

            end
        end

        function update_FigXlimLive(app)
            % Clear the current axes in the figure
            cla(app.FigXlimLive)

            x_signal = 1:length(table2array(app.base.project.session.ms_data(:, 1)));
            y_signal = table2array(app.base.project.session.ms_data(:, 2:end));

            % If clear_plots is false, plot the signal area
            a_h = area(app.FigXlimLive, x_signal, sum(y_signal,2), 'FaceColor', [1 1 1], 'LineWidth', 1);
            % Disable hit testing for the area plot
            set(a_h, 'hittest', 'off');
            % Set the y-axis to logarithmic scale
            set(app.FigXlimLive, 'YScale', 'log');

            xlim(app.FigXlimLive, [min(x_signal), max(x_signal)])

            % Determine the range of x_signal within the specified x-limits
            range = x_signal >= app.EditField_xlimMin.Value & x_signal <= app.EditField_xlimMax.Value;

            % Draw vertical lines at specified x-limits
            app.hVerticalLines(1:2) = [xline(app.FigXlimLive, app.EditField_xlimMin.Value, 'Color', 'red', "LineWidth", 3), ...
                xline(app.FigXlimLive, app.EditField_xlimMax.Value, 'Color', 'blue', "LineWidth", 3)];
            % Disable hit testing for the vertical lines
            set(app.hVerticalLines(1:2), 'hittest', 'off');

            % Calculate the new x-limits based on the signal range and specified x-limits
            xlimval = [min([app.EditField_xlimMin.Value, min(x_signal(range))]), ...
                max([app.EditField_xlimMax.Value, max(x_signal(range))])];
            app.xlimits = xlimval;

            % Att: this crashes if the Editfield_xlimMax.Value is set to a lower value than the Editfield_xlimMin.Value
            try
                % Set the x-limits of the zoomed plot
                xlim(app.ZoomedPlot, xlimval)
            catch
                % Display a message if setting x-limits fails
                disp('no xlim update in xlim(app.FigXlimLive, xlimval')
            end

        end

        function UpdateZoomedPlot(app)
            cla(app.ZoomedPlot)

            x_signal = 1:length(table2array(app.base.project.session.ms_data(:, 1))); %table2array(app.base.project.session.ms_data(:, 1));
            y_signal = table2array(app.base.project.session.ms_data(:, 2:end));

            %% plot the main plot
            x = x_signal;

            % if selected_analytes is empty, e.g. at initializing the plot
            % it will plot all analytes
            if isempty(app.selected_analytes)
                y = y_signal;
            else
                y = table2array(app.base.project.session.ms_data(:, app.selected_analytes));
            end

            plot(app.ZoomedPlot,x ,y ,'LineWidth',1)

            % Set the y-axis to logarithmic scale
            %set(app.ZoomedPlot, 'YScale', 'log');

            if app.LogScaleCheckBox.Value
                set(app.ZoomedPlot, 'YScale', 'log');
            else
                set(app.ZoomedPlot, 'YScale', 'linear');
            end

            %% set the xlimits according to FigXlimLive
            if min(app.xlimits) == max(app.xlimits)
                xlim(app.ZoomedPlot,'auto')
            else
                xlim(app.ZoomedPlot,sort([min(app.xlimits) max(app.xlimits)]))
            end

            %% this plots both start and end and no area
            % % When the signals are detected, plot them in the ZoomedPlot
            % if isfield(app.base.project.session, 'signal_starts') && isfield(app.base.project.session, 'signal_ends')
            %     hold(app.ZoomedPlot, 'on')
            %
            %     y_signal_nonzero = y_signal(y_signal ~= 0);
            %     y_min = min(y_signal_nonzero);
            %     y_max = max(y_signal(:));
            %
            %     for i = 1:numel(app.base.project.session.signal_starts)
            %         start_idx = app.base.project.session.signal_starts(i);
            %         end_idx = app.base.project.session.signal_ends(i);
            %         if start_idx >= 1 && start_idx <= numel(x)
            %             line(app.ZoomedPlot, [x(start_idx), x(start_idx)], [y_min, y_max], 'Color', 'k', 'LineStyle', '--', 'LineWidth', 1);
            %         end
            %         if end_idx >= 1 && end_idx <= numel(x)
            %             line(app.ZoomedPlot, [x(end_idx), x(end_idx)], [y_min, y_max], 'Color', 'k', 'LineStyle', '--', 'LineWidth', 1);
            %         end
            %     end
            %     hold(app.ZoomedPlot, 'off')
            % end
            %% This plots always the ends with the grey areas in between

            % When the signals are detected, plot them in the ZoomedPlot
            if app.PlotSignalRangesCheckBox.Value && isfield(app.base.project.session, 'spot') && isfield(app.base.project.session.spot, 'signal_starts') && isfield(app.base.project.session.spot, 'signal_ends')

                hold(app.ZoomedPlot, 'on')

                y_signal_nonzero = y_signal(y_signal ~= 0);
                y_min = min(y_signal_nonzero);
                y_max = max(y_signal(:));

                % Calculate average length of signal_end to signal_end intervals
                signal_starts = app.base.project.session.spot.signal_starts;
                signal_ends = app.base.project.session.spot.signal_ends;
                if numel(signal_ends) > 1
                    avg_interval = mean(diff(signal_ends));
                else
                    avg_interval = 0;
                end

                % Plot grey areas and lines
                for i = 1:numel(signal_ends)
                    if i == 1
                        % For the first interval, use the average length
                        start_idx = max(1, signal_ends(i) - round(avg_interval));
                    else
                        start_idx = signal_ends(i-1);
                    end
                    end_idx = signal_ends(i);

                    if start_idx >= 1 && start_idx <= numel(x) && end_idx >= 1 && end_idx <= numel(x)
                        % Alternate between lighter and slightly darker grey
                        if mod(i, 2) == 0
                            grey_color = [0.6, 0.6, 0.6];
                        else
                            grey_color = [0.8, 0.8, 0.8];
                        end

                        % Fill the area between the lines with transparency
                        fill(app.ZoomedPlot, [x(start_idx), x(start_idx), x(end_idx), x(end_idx)], [y_min, y_max, y_max, y_min], ...
                            grey_color, 'FaceAlpha', 0.3, 'EdgeColor', 'none');
                    end

                    % Draw the lines
                    line(app.ZoomedPlot, [x(signal_starts(i)), x(signal_starts(i))], [y_min, y_max], 'Color', 'k', 'LineStyle', '--', 'LineWidth', 1);
                    line(app.ZoomedPlot, [x(end_idx), x(end_idx)], [y_min, y_max], 'Color', 'k', 'LineStyle', '--', 'LineWidth', 1);

                    %% plot the names if the checkbox is checked
                    if app.ShowSpotnamesCheckBox.Value
                        % Add spot name text in the top left corner of the area
                        if isfield(app.base.project.session, 'spotname') && i <= numel(app.base.project.session.spotname)
                            spotname = app.base.project.session.spotname{i};
                            text(app.ZoomedPlot, x(start_idx)+5, y_max, spotname, 'VerticalAlignment', 'top', 'HorizontalAlignment', 'left', 'FontSize', 10, 'Color', 'k', 'Interpreter', 'none');
                        end
                    end

                end

                hold(app.ZoomedPlot, 'off')

            end


        end

        function update_ZoomedPlot_Legend_fun(app)
            % this function updates the analytes_listbox when needed

            % this function updates the analytes_listbox when needed
            el_names = app.base.project.session.ms_data(:, 2:end).Properties.VariableNames; %read out the names of the analytes from each signal
            app.ZoomedPlot_Legend.Items = el_names; %fills up the analytes_listbox with the names
            app.ZoomedPlot_Legend.ItemsData=2:numel(el_names);

        end

        % function to update the Dwell Times and spotnames if they are changed in the popup table

        function updateDwellTimes(app, src, event)
            % Get the edited data
            newData = src.Data;

            % Update the dwelltimes in the data structure
            app.base.project.session.dwelltimes = newData.DwellTimes;
        end

        function updateLogfileInformations(app, src, event)
            % Get the edited data
            newData = src.Data;

            % Update the spotnames in the data structure
            app.base.project.session.spotname = newData.Spotname;

        end

        function [signal_starts, signal_ends, cleaningshot_starts, cleaningshot_ends] = detect_signals(app, fullsignal_raw, num_spots)
            % Extracting information out of the raw data
            fullsignal_processed = fullsignal_raw;
            fullsignal_processed.totalcounts = sum(table2array(fullsignal_raw(:,2:end)), 2, 'omitnan');

            % Normalizing the totalcounts
            min_totalcounts = min(fullsignal_processed.totalcounts);
            max_totalcounts = max(fullsignal_processed.totalcounts);

            % Bisection method to find the optimal threshold
            threshold_low = 0;
            threshold_high = 1;
            tolerance = 1e-9;

            while (threshold_high - threshold_low) > tolerance
                threshold_mid = (threshold_low + threshold_high) / 2;
                [signal_starts, signal_ends, cleaningshot_starts, cleaningshot_ends] = classify_signals(threshold_mid, fullsignal_processed, min_totalcounts, max_totalcounts);

                if length(signal_starts) == num_spots && length(signal_ends) == num_spots
                    break;
                elseif length(signal_starts) < num_spots
                    threshold_high = threshold_mid;
                else
                    threshold_low = threshold_mid;
                end
            end

            % Final classification with the optimal threshold
            optimal_threshold = threshold_mid;
            [signal_starts, signal_ends, cleaningshot_starts, cleaningshot_ends] = classify_signals(optimal_threshold, fullsignal_processed, min_totalcounts, max_totalcounts);

            % Function to classify data points and count signal starts and ends
            function [signal_starts, signal_ends, cleaningshot_starts, cleaningshot_ends] = classify_signals(threshold, fullsignal_processed, min_totalcounts, max_totalcounts)
                fullsignal_processed.normalized_totalcounts = (fullsignal_processed.totalcounts - min_totalcounts) / (max_totalcounts - min_totalcounts);
                fullsignal_processed.classification = zeros(height(fullsignal_processed), 1); % Initialize the classification column
                fullsignal_processed.classification(fullsignal_processed.normalized_totalcounts >= threshold) = 1;

                % Clear out single spikes
                logicalArray = fullsignal_processed.classification;
                logicalArray = reshape(logicalArray, 1, []);
                charArray = num2str(logicalArray);
                charArray = strrep(charArray, ' ', '');
                pattern_spike = '0{1}1{1}0{1}';
                spikes = (regexp(charArray, pattern_spike))+1;

                for o = spikes
                    fullsignal_processed.classification(o) = 0;
                    fullsignal_processed.normalized_totalcounts(o) = fullsignal_processed.normalized_totalcounts(o+1);
                end

                % Find where the signals start and end
                starts = find(diff(fullsignal_processed.classification == 1) == 1);
                ends = find(diff(fullsignal_processed.classification == 1) == -1);

                % Filter the ends of the signals
                signal_starts = [];
                signal_ends = [];
                cleaningshot_starts = [];
                cleaningshot_ends = [];

                lengths = ends - starts;

                for i = 1:length(ends)
                    if ends(i) - starts(i) > mean(lengths)
                        signal_starts = [signal_starts; starts(i)];
                        signal_ends = [signal_ends; ends(i)];
                    else
                        cleaningshot_starts = [cleaningshot_starts; starts(i)];
                        cleaningshot_ends = [cleaningshot_ends; ends(i)];
                    end
                end
            end


        end

        function [signal_starts, signal_ends] = detect_signals_via_time(app, fullsignal_raw, laser_start_times, laser_stop_times)

            sweeptime = mean(diff(table2array(fullsignal_raw(2:10,1))));

            % Initialize output arrays
            signal_starts = zeros(size(laser_start_times));
            signal_ends = zeros(size(laser_stop_times));

            % Get time column from data (in seconds)
            time_array = table2array(fullsignal_raw(:,1));

            % Convert cell array of datetime strings to datetime array
            start_times = cellfun(@datetime, laser_start_times);
            stop_times = cellfun(@datetime, laser_stop_times);

            % Convert to seconds since start of measurement
            t0 = start_times(1);
            start_secs = seconds(start_times - t0);
            stop_secs = seconds(stop_times - t0);

            % Handle global signal indent
            if ~isempty(app.global_signal_indent) && app.global_signal_indent > 0
                indent = app.global_signal_indent;
            else
                indent = 1;
            end

            % Find corresponding indices for each laser start/stop time
            % Find corresponding indices for each laser start/stop time
            for i = 1:length(start_secs)
                % Find closest time index and apply indent
                [~, start_idx] = min(abs(time_array - start_secs(i)));
                [~, end_idx] = min(abs(time_array - stop_secs(i)));

                % Apply the indent to both start and end indices
                signal_starts(i) = start_idx + indent;
                signal_ends(i) = end_idx + indent;
            end
        end
      

        %%%%%%%%%%% Tab Measurement File + Laser Logfile %%%%%%%%%%

        function update_Imp_Mod_GUI_single_spot_Tab(app)

            % here populate the spotname listbox
            app.SingleSpotsListBox.Items = app.base.project.session.spotname;
            app.SingleSpotsListBox.ItemsData = 1:numel(app.base.project.session.spotname);

            app.LogFileDropDown_Single.Items = app.ChooseLaserDropDown.Items;
            app.LogFileDropDown_Single.ItemsData = app.ChooseLaserDropDown.ItemsData;

            update_single_spot_plot(app)

            %update_progress_buttons(app)
        end

        function update_single_spot_plot(app)
            cla(app.SingleSpotAxes)

            % here check which spot is selected
            if isempty(app.selected_spot)
                app.selected_spot = 1;
            end

            spot_to_plot = app.base.project.session.spot.ms_data_single_spot{app.selected_spot};

            %% plot the main plot
            % Att this x_default has to change if single signals are input
            x_default = 1:height(spot_to_plot);
            y_default = table2array(spot_to_plot(:, 2:end)); %

            plot(app.SingleSpotAxes, x_default, y_default)

            set(app.SingleSpotAxes, 'YScale', 'log')
            xlim(app.SingleSpotAxes, [1, height(spot_to_plot)])
            title(app.SingleSpotAxes, app.base.project.session.spotname(app.selected_spot), 'Interpreter', 'none');

            % Add vertical lines for signal_starts and signal_ends
            signal_starts = app.base.project.session.spot.signal_starts(app.selected_spot);
            signal_ends = app.base.project.session.spot.signal_ends(app.selected_spot);

            hold(app.SingleSpotAxes, 'on')
            for i = 1:length(signal_starts)
                xline(app.SingleSpotAxes, signal_starts(i), 'b--', 'LineWidth', 1.5);
            end
            for i = 1:length(signal_ends)
                xline(app.SingleSpotAxes, signal_ends(i), 'b--', 'LineWidth', 1.5);
            end
            hold(app.SingleSpotAxes, 'off')

        end

        % next detect signal starts and ends, then update_spot_plot
        function [signal_starts, signal_ends]  = detect_single_spot_signal_starts_ends(app, single_spot)


            %% extracting informations out of the raw data

            single_spot.totalcounts = sum(table2array(single_spot(:,2:end)),2,'omitnan');
            single_spot.index = (1:height(single_spot))';

            %% define the background and the signal --> signal = 1, background = 0

            % Min-Max Normalization
            min_val = min(single_spot.totalcounts);
            max_val = max(single_spot.totalcounts);
            single_spot.normalized_counts = (single_spot.totalcounts - min_val) / (max_val - min_val);

            % Classify the data points where the normalized counts are less than a threshold as background
            threshold = 0.005; % Adjust the threshold as needed
            single_spot.classification(single_spot.normalized_counts < threshold) = 0;
            single_spot.classification(single_spot.normalized_counts >= threshold) = 1;

            %% clear out single spikes
            % 
            % %Define a logical array
            % logicalArray = single_spot.classification;
            % % Reshape the logical array into a row vector
            % logicalArray = reshape(logicalArray, 1, []);
            % % Convert the logical array to a string
            % charArray = num2str(logicalArray);
            % % Remove any whitespace in the string
            % charArray = strrep(charArray, ' ', '');
            % % Define the pattern you want to find
            % pattern_spike = '0{1}1{1}0{1}'; %for patterns like 010
            % 
            % spikes = (regexp(charArray, pattern_spike))+1;
            % 
            % for o = spikes
            %     single_spot.classification(o) = 0;
            %     single_spot.normalized_counts(o) = single_spot.normalized_counts(o+1);
            % end

            % Detect signal starts and ends
            starts = find(diff([0; single_spot.classification]) == 1);
            ends = find(diff([single_spot.classification; 0]) == -1);

            %% Select the appropriate start and end indices
            if isempty(starts) || isempty(ends)
                signal_starts = [];
                signal_ends = [];
            else
                signal_starts = min(starts);
                signal_ends = max(ends(ends > signal_starts));
            end

        end

    
    end


    methods (Access = public)

        %%%%%%%%%%%%%%%%%% SILLS projects %%%%%%%%%%%%%%%

        function [settings, ms_data_single_spot, measurementpath, spotname, time] = read_SILLS_projects_public(app, measurementpath, settings)


            keyboard

            % i need the cps data, the indices of the intervals, the
            % acquisition times and the dwelltimes.

            % Also get the spot classification - PRM or UNK
            % the user than has to redo the assignment of the PRM to a
            % reference material

            % so the user is still forced to click through the modules.
            % this is also necessary because the drift has to be calculated

            % Then also get the internal
            % standard elements and mass fractions.


% basically this function should extract everything and put it in the right
% format.
% then the data that is already in the right format will be injected in the
% datastructure one time in the button callback and the other time with the
%  SILLS_project_import_public function which will handle the
% API shit


        end

        function [settings] = SILLS_project_import_public(app, measurementpath, settings)

            try
                test_state = true; % is needed later as a failsafe so that no broken data gets into the data structure

                % if settings is not sent or something wrong is put in
                if not(exist('settings', 'var')) || not(isstruct(settings))
                    settings = struct();
                end

                % if the measurementpath is empty, send the settings back
                if not(exist('measurementpath','var')) || isempty(measurementpath)
                    measurementpath = [];
                    [settings, ~, ~, ~, ~] = read_SILLS_projects_public(app, measurementpath, settings);
                    test_state = false;
                end


                % if the session_name is not input
                if not(isfield(settings,'session_name')) || isempty(settings.session_name)
                    settings.session_name = 'New Sesh';
                end

                % if the project_name is not sent, just call it new project
                if not(isfield(settings,'project_name')) || isempty(settings.project_name)
                    settings.project_name = 'New Proj';
                end

                % if the session_name is not input, make it empty
                if not(isfield(settings,'session_id')) || isempty(settings.session_id)
                    settings.session_id = 0; % default value
                end

                % if the project_id is not sent, set default to 0
                if not(isfield(settings,'project_id')) || isempty(settings.project_id)
                    settings.project_id = 0;    % default value
                end

                if not(isfield(settings,'reductiontype')) || isempty(settings.reductiontype)
                    settings.reductiontype = 'Inclusion'; % default value
                end

                % run the functions and save the output in a cache
                [settings, ms_data_single_spot, measurementpath, spotname, time] = read_single_spot_files_public(app, measurementpath, settings);

                % if test_state = false, push the data from the cache to the
                % data structure
                if test_state == false
                    return
                else
                    % Initialize the data structure if it does not exist
                    if ~isfield(app.base, 'project')
                        app.base.project = struct();
                    end
                    if ~isfield(app.base.project, 'session')
                        app.base.project.session = struct();
                    end
                    if ~isfield(app.base.project.session, 'dwelltimes')
                        app.base.project.session.dwelltimes = [];
                        app.base.project.session.dwelltimepath = [];
                    end
                    if ~isfield(app.base.project.session, 'spot')
                        app.base.project.session.spot = struct();
                        app.base.project.session.spot.ms_data_single_spot = [];
                        app.base.project.session.measurementpath = {};
                        app.base.project.session.spot.acquisition_time = {};
                        app.base.project.session.spotname = {};
                    end
                    
                    app.base.project.session.analytes = all_analytes;

                    % Push the data to the data structure
                    app.base.project.session.spot.ms_data_single_spot = ms_data_single_spot;
                    app.base.project.session.spot.ms_data_single_spot_raw = ms_data_single_spot;
                    % do the rest
                    app.base.project.session.measurementpath = char(measurementpath);
                    app.base.project.session.spot.acquisition_time = time;
                    app.base.project.session.spotname = char(spotname);

                    % Detect the signals and store the results
                    [signal_starts, signal_ends] = detect_single_spot_signal_starts_ends(app, ms_data_single_spot);
                    app.base.project.session.spot.signal_starts(index,1) = signal_starts;
                    app.base.project.session.spot.signal_ends(index,1) = signal_ends;
                    
                    % here store the intervals

                    % store the dwelltimes
                    app.base.project.session.dwelltimes = dwelltimes;
                    app.base.project.session.dwelltimepath =  dwelltimepath;

                    % store the classification

                    % store the IS elements and mass fractions

                    % store project and session related variables
                    app.base.project.session.session_name = settings.session_name;
                    app.base.project_name = settings.project_name;
                    app.project_id = settings.project_id;
                    app.session_id = settings.session_id;

                    % store the type of import
                    app.base.project.session.meta.datatype = 'Single';
                    app.base.project.session.meta.reductiontype = settings.reductiontype;

                end

                if isfield(settings, 'import_module_GUI_update_state') && settings.import_module_GUI_update_state == true
                    %update_Imp_Mod_GUI_single_spot_Tab(app)
                end

            catch ME
                DisplayError(ME,'SILLS_project_import_public failed')

                message = "SILLS_project_import_public failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                disp('SILLS_project_import_public failed')
            end

            % if ~isequal(sort(app.base.project.session.analytes), sort(app.base.project.session.dwelltimeanalytes))
            %     disp("Analytes from measurement file and dwelltime file are different")
            % end


        end


        %%%%%%%%%%%%%%%%%% Single Spots (Shot-by-Shot) %%%%%%%%%%%%%%%

        % here I need the filename as spotname, the time that is printed as
        % acquisition time and the spotdata all from the .csv

        function [settings, ms_data_single_spot, measurementpath, spotname, time] = read_single_spot_files_public(app, measurementpath, settings)

            ms_data_single_spot = [];
            time = '';

            if not(exist('measurementpath','var')) || isempty(measurementpath)
                settings.massspec.Value = app.ChooseMassSpecSSDropDown.Value; % default value
                settings.massspec.Options = {'Agilent 7900','Perkin Elmers', 'Thermo Fisher Element XR'};
                return
            end

            if exist('settings','var')&& isfield(settings,'massspec')
                switch settings.massspec.Value
                    case 'Agilent 7900'
                        % Read the file content
                        fileContent = fileread(measurementpath);

                        % Extract the time from the header
                        timePattern = 'Acquired\s*:\s*\d{2}/\d{2}/\d{4}\s*(\d{2}:\d{2}:\d{2})';
                        timeMatch = regexp(fileContent, timePattern, 'tokens');
                        if ~isempty(timeMatch)
                            time = timeMatch{1}{1};
                        end

                        ms_data_single_spot = readtable(measurementpath); % instead of this, also the filename and the time needs to be extracted

                        % Remove the last row if it contains NaN
                        if any(isnan(ms_data_single_spot{end, :}))
                            ms_data_single_spot(end, :) = [];
                        end

                        % Remove the .csv extension from the filename
                        [~, spotname, ~] = fileparts(measurementpath);

                        app.base.project.session.meta.mass_spec = 'Agilent 7900';

                    case 'PerkinElmer'

                        % Get file info to extract last modified time
                        fileInfo = dir(measurementpath);
                        time = datestr(fileInfo.datenum, 'HH:MM:SS');

                        ms_data_single_spot = readtable(measurementpath, 'Delimiter', ',', 'FileType', 'text');

                        % Remove the .csv extension from the filename
                        [~, spotname, ~] = fileparts(measurementpath);

                        app.base.project.session.meta.mass_spec = 'PerkinElmer';

                    case 'Thermo Fisher Element XR'

                        fileContent = fileread(measurementpath);
                        % Extract the time from the header
                        timePattern = '\w+, \w+ \d+, \d+ (\d+:\d+:\d+)';
                        timeMatch = regexp(fileContent, timePattern, 'tokens');
                        if ~isempty(timeMatch)
                            time = timeMatch{1}{1};
                        end

                        ms_data_single_spot = readtable(measurementpath, 'Delimiter', ',', 'FileType', 'text', ...
                            'HeaderLines', 7);

                        % Remove the .csv extension from the filename
                        [~, spotname, ~] = fileparts(measurementpath);

                        app.base.project.session.meta.mass_spec = 'Thermo Fisher Element XR';
                end
            end

        end

        % function [settings, acquisition_times] = read_acquisition_times_public(app, acquisitiontimepath, settings)
        % 
        %     acquisition_times = [];
        % 
        %     if not(exist('acquisitiontimepath','var')) || isempty(acquisitiontimepath)
        %         settings.acquisition_time_type.Value = app.LogFileDropDown_Single.Value; % default value
        %         settings.acquisition_time_type.Options = {'From .csv template','second option'};
        %         return
        %     end
        % 
        %     if exist('settings','var')&& isfield(settings,'acquisition_time_type')
        %         switch settings.acquisition_time_type.Value
        %             case 'From .csv template'
        % 
        %                 acquisition_times = readtable(acquisitiontimepath);
        % 
        %             case 'option2'
        %                 % function for different icp
        %             case 'option3'
        %                 % function for different icp
        %         end
        %     end
        % 
        % end
        
        %%%%%%%%%%%%%%%%%% API public functions for import of single spots shot by shot %%%%%%%%%%%%%%%
        % this function is a wrapper for the above functions
        % it is only used in the API and not in the app itself
        function [settings] = single_spot_import_public(app, measurementpath, dwelltimepath, settings)

            try
                test_state = true; % is needed later as a failsafe so that no broken data gets into the data structure

                % if settings is not sent or something wrong is put in
                if not(exist('settings', 'var')) || not(isstruct(settings))
                    settings = struct();
                end

                % if the measurementpath is empty, send the settings back
                if not(exist('measurementpath','var')) || isempty(measurementpath)
                    measurementpath = [];
                    [settings, ~, ~, ~, ~] = read_single_spot_files_public(app, measurementpath, settings);
                    test_state = false;
                end

                % if the dwelltimepath is empty, send the settings back
                if not(exist('dwelltimepath','var')) || isempty(dwelltimepath)
                    dwelltimepath = [];
                    [settings, ~, ~, ~] = read_dwelltime_file_public(app, dwelltimepath, settings);
                    test_state = false;
                end

                % if the session_name is not input
                if not(isfield(settings,'session_name')) || isempty(settings.session_name)
                    settings.session_name = 'New Sesh';
                end

                % if the project_name is not sent, just call it new project
                if not(isfield(settings,'project_name')) || isempty(settings.project_name)
                    settings.project_name = 'New Proj';
                end

                % if the session_name is not input, make it empty
                if not(isfield(settings,'session_id')) || isempty(settings.session_id)
                    settings.session_id = 0; % default value
                end

                % if the project_id is not sent, set default to 0
                if not(isfield(settings,'project_id')) || isempty(settings.project_id)
                    settings.project_id = 0;    % default value
                end

                if not(isfield(settings,'massspec')) || isempty(settings.massspec)
                    disp("Please select a Mass-Spec")
                    return
                end

                if not(isfield(settings,'reductiontype')) || isempty(settings.reductiontype)
                    settings.reductiontype = 'Matrix'; % default value
                end

                % run the functions and save the output in a cache
                [settings, ms_data_single_spot, measurementpath, spotname, time] = read_single_spot_files_public(app, measurementpath, settings);
                [settings, dwelltimes, dwelltimepath] = read_dwelltime_file_public(app, dwelltimepath, settings);

                % if test_state = false, push the data from the cache to the
                % data structure
                if test_state == false
                    return
                else
                    % Initialize the data structure if it does not exist
                    if ~isfield(app.base, 'project')
                        app.base.project = struct();
                    end
                    if ~isfield(app.base.project, 'session')
                        app.base.project.session = struct();
                    end
                    if ~isfield(app.base.project.session, 'dwelltimes')
                        app.base.project.session.dwelltimes = [];
                        app.base.project.session.dwelltimepath = [];
                    end
                    if ~isfield(app.base.project.session, 'spot')
                        app.base.project.session.spot = struct();
                        app.base.project.session.spot.ms_data_single_spot = [];
                        app.base.project.session.measurementpath = {};
                        app.base.project.session.spot.acquisition_time = {};
                        app.base.project.session.spotname = {};
                    end
                    
                    all_analytes = {};
                    % Collect analytes from the current file
                    current_analytes = ms_data_single_spot.Properties.VariableNames(2:end)';
                    if isempty(all_analytes)
                        all_analytes = current_analytes;
                    else
                        if ~isequal(sort(all_analytes), sort(current_analytes))
                            %message = "Only load files that contain the same analytes!";
                            %uialert(app.UIFigure, message, "Warning", "Icon", "warning");
                            disp('Only load files that contain the same analytes!')
                            return;
                        end
                    end
                    % Check if there are already analytes in the data structure
                    if isfield(app.base.project.session, 'analytes') && ~isempty(app.base.project.session.analytes)
                        if ~isequal(sort(app.base.project.session.analytes), sort(all_analytes))
                            %message = "Only load files that contain the same analytes!";
                            %uialert(app.UIFigure, message, "Warning", "Icon", "warning");
                            disp('Only load files that contain the same analytes!')
                            return;
                        end
                    end
                    app.base.project.session.analytes = all_analytes;

                    % Determine the index for the new data
                    index = numel(app.base.project.session.spot.ms_data_single_spot) + 1;

                    % Push the data to the data structure
                    app.base.project.session.spot.ms_data_single_spot{index,1} = ms_data_single_spot;
                    app.base.project.session.spot.ms_data_single_spot_raw{index,1} = ms_data_single_spot;
                    % do the rest
                    app.base.project.session.measurementpath{index,1} = char(measurementpath);
                    app.base.project.session.spot.acquisition_time{index,1} = time;
                    app.base.project.session.spotname{index,1} = char(spotname);

                    % Detect the signals and store the results
                    [signal_starts, signal_ends] = detect_single_spot_signal_starts_ends(app, ms_data_single_spot);
                    app.base.project.session.spot.signal_starts(index,1) = signal_starts;
                    app.base.project.session.spot.signal_ends(index,1) = signal_ends;

                    % store the dwelltimes
                    app.base.project.session.dwelltimes = dwelltimes;
                    app.base.project.session.dwelltimepath =  dwelltimepath;

                    % store project and session related variables
                    app.base.project.session.session_name = settings.session_name;
                    app.base.project_name = settings.project_name;
                    app.project_id = settings.project_id;
                    app.session_id = settings.session_id;

                    % store the type of import
                    app.base.project.session.meta.datatype = 'Single';
                    app.base.project.session.meta.reductiontype = settings.reductiontype;

                end

                if isfield(settings, 'import_module_GUI_update_state') && settings.import_module_GUI_update_state == true
                    update_Imp_Mod_GUI_single_spot_Tab(app)
                end

            catch ME
                DisplayError(ME,'single_spot_import_public failed')

                message = "single_spot_import_public failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                disp('single_spot_import_public failed')
            end

            % if ~isequal(sort(app.base.project.session.analytes), sort(app.base.project.session.dwelltimeanalytes))
            %     disp("Analytes from measurement file and dwelltime file are different")
            % end


        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%% Measurement + Logfile Import %%%%%%%%%%%%%%%

        function [spotname, spotsize, rep_rates, fluences, laser_start_times, laser_stop_times] = read_resonetics_verbose_log(app, filepath)
            % Open the file
            logfile = fopen(filepath,'r');

            % Check if the file was opened successfully
            if logfile == -1
                error('Cannot open file')
            end

            % Define the patterns to search for
            pattern_pointnumbers = 'Point #(\d+)';
            pattern_spotname = 'Comment: "(.*)"';
            pattern_spotsize = 'Spot Size: (\d+) µm';
            pattern_date = '\d{4}-\d{2}-\d{2}';
            pattern_rep_rate = 'Rep\. Rate: (\d+\.?\d*) Hz';
            pattern_fired = 'Fired: (\d+)';

            pattern_laser_start = '(\d{4}-\d{2}-\d{2} \d{2}:\d{2}:\d{2}.\d{3})\tLaser Started';
            pattern_laser_stop = '(\d{4}-\d{2}-\d{2} \d{2}:\d{2}:\d{2}.\d{3})\tLaser Stopped';
            pattern_fluence = 'Requested On-Sample Fluence: (\d+\.?\d*) J\.cm';

            % Initialize empty arrays and cells and variables
            pointnumbers = [];
            spotsize = [];
            measuringdate = {};
            spotname = {};
            %found_rep_rate = false;
            is_cleaning = false;
            found_ablation = false;
            fired = [];
            laser_start_times = {};
            laser_stop_times = {};
            rep_rates = [];
            fluences = [];

            % Extract the date from the first line
            lineone = fgets(logfile);
            matches_date = regexp(lineone, pattern_date, 'match');
            if ~isempty(matches_date)
                % Rewrite the date
                date_parts = split(matches_date{1}, '-');
                measuringdate = [date_parts{3}, date_parts{2}, date_parts{1}];
            end

            % Read the file line by line
            while ~feof(logfile)
                line = fgets(logfile);

                % Check if we're in cleaning section
                if contains(line, 'Performing Cleaning')
                    is_cleaning = true;
                end

                % Reset cleaning flag when we see Rep. Rate (indicates start of new measurement)
                if contains(line, 'Rep. Rate:')
                    is_cleaning = false;
                end

                % Check for spot size (only when not in cleaning section)
                if contains(line, 'Spot Size:') && ~is_cleaning
                    matches_spotsize = regexp(line, pattern_spotsize, 'tokens');
                    if ~isempty(matches_spotsize)
                        spotsize = [spotsize; str2double(matches_spotsize{1}{1})];
                    end
                end

                % Check for laser start time
                matches_start = regexp(line, pattern_laser_start, 'tokens');
                if ~isempty(matches_start)
                    laser_start_times = [laser_start_times; matches_start{1}{1}];
                end

                % Check for laser stop time
                matches_stop = regexp(line, pattern_laser_stop, 'tokens');
                if ~isempty(matches_stop)
                    laser_stop_times = [laser_stop_times; matches_stop{1}{1}];
                end

                % Check for rep rate
                matches_rep_rate = regexp(line, pattern_rep_rate, 'tokens');
                if ~isempty(matches_rep_rate)
                    rep_rates = [rep_rates; str2double(matches_rep_rate{1}{1})];
                end

                % Check for fluence
                matches_fluence = regexp(line, pattern_fluence, 'tokens');
                if ~isempty(matches_fluence)
                    fluences = [fluences; str2double(matches_fluence{1}{1})];
                end

                % check for = Ablation =
                if contains(line, '= Ablation =')
                    found_ablation = true;
                end

                % get the fired value
                if contains(line, 'Fired:') && found_ablation
                    % Extract the fired value
                    matches_fired = regexp(line, pattern_fired, 'tokens');
                    if ~isempty(matches_fired)
                        % Add the fired value to the array
                        fired = [fired; str2double(matches_fired{1}{1})];
                    end
                    found_ablation = false;
                end

                % get the spot number
                if contains(line, 'Point #')
                    % Extract the number after "Point #"
                    matches_pointnumbers = regexp(line, pattern_pointnumbers, 'tokens');
                    if ~isempty(matches_pointnumbers)
                        % Convert the number to a double and append it to the array
                        pointnumbers = [pointnumbers; str2double(matches_pointnumbers{1}{1})];
                    end
                end

                % get the spotname
                if contains(line, 'Comment:')
                    % Extract the comment
                    matches_spotname = regexp(line, pattern_spotname, 'tokens');
                    if ~isempty(matches_spotname)
                        % Add the comment to the cell array
                        spotname = [spotname; matches_spotname{1}{1}];
                    end
                end

                % % check for Rep. Rate
                % if contains(line, pattern_rep_rate)
                %     found_rep_rate = true;
                % end
                % 
                % % get the spot size
                % if contains(line, 'Spot Size:') && found_rep_rate
                %     % Extract the spot size
                %     matches_spotsize = regexp(line, pattern_spotsize, 'tokens');
                %     if ~isempty(matches_spotsize)
                %         % Add the spot size to the cell array
                %         spotsize = [spotsize; str2double(matches_spotsize{1}{1})];
                %     end
                %     found_rep_rate = false;
                % end
            end

            fclose(logfile);

            %% be sure that every entry made above is deleted here if the laser did not fire
            % this could be somehow connected to the check of time intervals of the
            % signals below

            for i=length(fired):-1:1
                if fired(i) == 0
                    % delete the ith entry in spotname, spotsize, pointnumbers and fired
                    spotname(i) = [];
                    spotsize(i) = [];
                    pointnumbers(i) = [];
                    fired(i) = [];
                    laser_start_times(i) = [];
                    laser_stop_times(i) = [];
                    rep_rates(i) = [];
                    fluences(i) = [];
                end
            end


        end

        % these functions are used in the app and are wrapped by measurement_logfile_import_public
        function [settings, spotname, spotsize, rep_rates, fluences, laser_start_times, laser_stop_times, logfilepath] = read_logfile_public(app, logfilepath, settings)

            spotname = [];
            spotsize = [];
            rep_rates = [];
            fluences = [];
            laser_start_times = [];
            laser_stop_times = [];
            measuringdate = [];

            if not(exist('logfilepath','var')) || isempty(logfilepath)
                settings.laser.Value = app.ChooseLaserDropDown.Value; % default value
                settings.laser.Options = {'Resonetics Verbose Log (.log)','Resonetics Log (.csv)'};
                return
            end

            if exist('settings','var')&& isfield(settings,'laser')
                switch settings.laser.Value
                    case 'Resonetics Verbose Log (.log)'
                        
                        %[spotname] = read_resonetics_verbose_log(app, logfilepath);
                        [spotname, spotsize, rep_rates, fluences, laser_start_times, laser_stop_times]  = read_resonetics_verbose_log(app, logfilepath);
                    
                    case 'Resonetics Log (.csv)'

                        logData = readtable(logfilepath, 'Delimiter', ',', 'ReadVariableNames', true);

                        % Extract spot names from the Comment column
                        commentCol = logData.Comment;

                        % Find rows that contain spot names (non-empty comments)
                        validComments = ~cellfun(@isempty, commentCol);

                        if any(validComments)
                            % Get unique spot names
                            spotname = commentCol(validComments);
                        end
                        
                    case 'Logbook'
                        % function for different laser


                end
            end

        end

        function [settings, ms_data, measurementpath] = read_mass_spec_file_public(app, measurementpath, settings)

            ms_data = [];

            if not(exist('measurementpath','var')) || isempty(measurementpath)
                settings.massspec.Value = app.ChooseMassSpecDropDown.Value; % default value
                settings.massspec.Options = {'Agilent 7900','second option'};
                return
            end

            if exist('settings','var')&& isfield(settings,'massspec')
                switch settings.massspec.Value
                    case 'Agilent 7900'
                        ms_data = readtable(measurementpath);
                        app.base.project.session.meta.mass_spec = 'Agilent 7900';
                    case 'option2'
                        % function for different icp
                    case 'option3'
                        % function for different icp
                end
            end

        end

        % this function is also used for single spots
        function [settings, dwelltimes, dwelltimepath] = read_dwelltime_file_public(app, dwelltimepath, settings)

            dwelltimes = [];

            if not(exist('dwelltimepath','var')) || isempty(dwelltimepath)
                settings.dwelltime.Value = app.ChooseDwellTimeDropDown.Value; % default value
                settings.dwelltime.Options={'Agilent AcqMethod.xml','Thermo Fisher Element XR'};
                return
            end

            if exist('settings','var')&& isfield(settings,'dwelltime')
                switch settings.dwelltime.Value
                    case 'Agilent AcqMethod.xml'

                        % Read the XML file
                        xmlData = xmlread(dwelltimepath);

                        % Get all IcpmsElement nodes
                        elements = xmlData.getElementsByTagName('IcpmsElement');

                        % Initialize arrays to store element names and integration times
                        elementNames = cell(1, elements.getLength);
                        integrationTimes = zeros(1, elements.getLength);

                        % Loop through each IcpmsElement node
                        for i = 0:elements.getLength-1
                            % Get the current IcpmsElement node
                            element = elements.item(i);

                            % Get the ElementName, IntegrationTime, and MZ nodes
                            elementNameNode = element.getElementsByTagName('ElementName').item(0);
                            integrationTimeNode = element.getElementsByTagName('IntegrationTime').item(0);
                            mzNode = element.getElementsByTagName('MZ').item(0);

                            % Extract the text content of the nodes
                            elementName = char(elementNameNode.getTextContent());
                            integrationTime = str2double(char(integrationTimeNode.getTextContent()));
                            mzValue = str2double(char(mzNode.getTextContent()));

                            % Combine element names with MZ values
                            elementNames{i+1} = [elementName, num2str(mzValue)];
                            integrationTimes(i+1) = integrationTime;
                        end

                        % Create a table with integration times as values and element names as column headers
                        dwelltimes = array2table(integrationTimes, 'VariableNames', elementNames);

                    case 'Thermo Fisher Element XR'
                        
                        % Open file
                        fid = fopen(dwelltimepath, 'r');

                        % Skip 9 lines
                        for i = 1:9
                            fgetl(fid);
                        end

                        % Read headers (line 10)
                        headerLine = fgetl(fid);
                        headers = textscan(headerLine, '%s', 'Delimiter', ',');
                        columnNames = headers{1};

                        % Read data (line 11)
                        dataLine = fgetl(fid);
                        data = textscan(dataLine, '%f', 'Delimiter', ',');
                        dwellTimeValues = data{1}';

                        % Close file
                        fclose(fid);

                        % Create a table with the data
                        dwelltimes = array2table(dwellTimeValues, 'VariableNames', columnNames);

                        % Convert all values in the table from milliseconds to seconds
                        dwelltimes = dwelltimes ./ 1000;

                    case '.xlsx'

                        % Read the Excel file
                        dwelltimes = readtable(dwelltimepath);
                        
                        % Get the variable names (column headers)
                        varNames = dwelltimes.Properties.VariableNames;
                        
                        % Get the first row of data (dwelltimes in seconds)
                        dwellTimeValues = table2array(dwelltimes(1,:));
                        
                        % Create a new table with just the dwelltimes
                        dwelltimes = array2table(dwellTimeValues, 'VariableNames', varNames);
                        
                end

            end
        end

        %%%%%%%%%%%%%%%%%% API public functions for import %%%%%%%%%%%%%%%
        % this function is a wrapper for the above functions
        % it is only used in the API and not in the app itself
        function [settings] = measurement_logfile_import_public(app, measurementpath, logfilepath, dwelltimepath, settings)

            try
                test_state = true; % is needed later as a failsafe so that no broken data gets into the data structure

                % if settings is not sent or something wrong is put in
                if not(exist('settings', 'var')) || not(isstruct(settings))
                    settings = struct();
                end

                % if the logfilepath is empty, send the settings back
                if not(exist('logfilepath','var')) || isempty(logfilepath)
                    logfilepath = [];
                    [settings, ~, ~, ~, ~, ~, ~, ~, ~]  = read_logfile_public(app, logfilepath, settings);
                    
                    test_state = false;
                end

                % if the measurementpath is empty, send the settings back
                if not(exist('measurementpath','var')) || isempty(measurementpath)
                    measurementpath = [];
                    [settings, ~, ~] = read_mass_spec_file_public(app, measurementpath, settings);
                    test_state = false;
                end

                % if the dwelltimepath is empty, send the settings back
                if not(exist('dwelltimepath','var')) || isempty(dwelltimepath)
                    dwelltimepath = [];
                    [settings, ~, ~] = read_dwelltime_file_public(app, dwelltimepath, settings);
                    test_state = false;
                end

                % if the session_name is not input
                if not(isfield(settings,'session_name')) || isempty(settings.session_name)
                    settings.session_name = 'New Sesh';
                end

                % if the project_name is not sent, just call it new project
                if not(isfield(settings,'project_name')) || isempty(settings.project_name)
                    settings.project_name = 'New Proj';
                end

                % if the session_name is not input, make it empty
                if not(isfield(settings,'session_id')) || isempty(settings.session_id)
                    settings.session_id = 0; % default value
                end

                % if the project_id is not sent, set default to 0
                if not(isfield(settings,'project_id')) || isempty(settings.project_id)
                    settings.project_id = 0;    % default value
                end

                if not(isfield(settings,'massspec')) || isempty(settings.massspec)
                    disp("Please select a Mass-Spec")
                    return
                end

                if not(isfield(settings,'laser')) || isempty(settings.laser)
                    disp("Please select a Laser")
                    return
                end

                if not(isfield(settings,'dwelltime')) || isempty(settings.dwelltime)
                    disp("Please select a dwelltime file")
                    return
                end
          
                if not(isfield(settings,'reductiontype')) || isempty(settings.reductiontype)
                    settings.reductiontype = 'Matrix'; % default value
                end

                % run the functions and save the output in a cache
                [settings, ms_data, measurementpath] = read_mass_spec_file_public(app, measurementpath, settings);
                [settings, spotname, spotsize, rep_rates, fluences, laser_start_times, laser_stop_times, logfilepath] = read_logfile_public(app, logfilepath, settings);
                [settings, dwelltimes, dwelltimepath] = read_dwelltime_file_public(app, dwelltimepath, settings);
                % if test_state = false, push the data from the cache to the
                % data structure
                if test_state == false
                    return
                else
                    % push the data to datastructure
                    app.base.project.session.ms_data = ms_data;
                    app.base.project.session.ms_data_raw = ms_data;

                    app.base.project.session.analytes = app.base.project.session.ms_data.Properties.VariableNames(2:end)';
                    app.base.project.session.measurementpath = measurementpath;

                    app.base.project.session.spotname=spotname;
                    app.base.project.session.logfilepath=logfilepath;

                    % Att how do I know they are in the right order?
                    app.base.project.session.dwelltimes = dwelltimes;
                    app.base.project.session.dwelltimepath =  dwelltimepath;

                    app.base.project.session.session_name = settings.session_name;
                    app.base.project_name = settings.project_name;

                    app.project_id = settings.project_id;
                    app.session_id = settings.session_id;

                    app.base.project.session.meta.datatype = 'Continuous';
                    app.base.project.session.meta.reductiontype = settings.reductiontype;

                end

                % Att detect the signals, has to be moved later
                [app.base.project.session.spot.signal_starts, app.base.project.session.spot.signal_ends] = detect_signals(app, app.base.project.session.ms_data, numel(app.base.project.session.spotname));

                if isfield(settings, 'import_module_GUI_update_state') && settings.import_module_GUI_update_state == true
                    update_Imp_Mod_GUI_Meas_Log_Tab(app)
                end

            catch ME
                DisplayError(ME,'measurement_logfile_import_public failed')

                message = "measurement_logfile_import_public failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
            end


        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            % sourceapp = app von der aus das ImportModule gecallt wird, 1st
            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            %% when the addnewsession button is pressed
            if exist("project_id","var") && exist("session_id","var") && not(exist("data", "var"))
                app.project_id = project_id;
                app.session_id = session_id;

                if isequal(0, app.session_id) % If this is the first time adding a session

                    app.base.project.session.session_name = "New Session";
                    app.UIFigure.Name = [app.base.project_name ' - ' char(app.base.project.session.session_name)];

                    app.base.project.session.meta.module_status.import = false;
                    app.base.project.session.meta.module_status.interval = false;                   
                    app.base.project.session.meta.module_status.classification = false;  
                    app.base.project.session.meta.module_status.drift = false;  
                    app.base.project.session.meta.module_status.quantification = false;  
                    app.base.project.session.meta.module_status.results = false;  

                elseif app.session_id > 0 % If an already existing session is opened

                    app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                    %app.base.project.session.session_name = "New Session #" + string(app.session_id);
                    app.UIFigure.Name = [app.base.project_name ' - ' char(app.base.project.session.session_name)];

                end
            end

            %% for API calls if someone wants to send data in
            if  exist("project_id","var") && exist("session_id","var") && exist("data", "var")
                % load the sessin_id and the data
                app.project_id = project_id;
                app.session_id = session_id;
                app.base.project.session.session_name = data.session_name;
                app.base.project_name = data.project_name;

                % adjust the name of the new session
                app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)];

                app.base.project.session.meta.module_status.import = true;
                app.base.project.session.meta.module_status.interval = false;
                app.base.project.session.meta.module_status.classification = false;
                app.base.project.session.meta.module_status.drift = false;
                app.base.project.session.meta.module_status.quantification = false;
                app.base.project.session.meta.module_status.results = false;

            end

            update_progress_buttons(app)

            %update GUI
            if ~isempty(app.base)
                if isfield(app.base.project.session, 'meta') && isfield(app.base.project.session.meta, 'datatype') && isequal(app.base.project.session.meta.datatype, 'Continuous')
                    update_Imp_Mod_GUI_Meas_Log_Tab(app)
                elseif isfield(app.base.project.session, 'meta') && isfield(app.base.project.session.meta, 'datatype') && isequal(app.base.project.session.meta.datatype, 'Single')
                    update_Imp_Mod_GUI_single_spot_Tab(app)
                end
            end

        end

        % Button down function: FigXlimLive
        function FigXlimLiveButtonDown(app, event)
            %%
            if isempty(app.hVerticalLines)
                return
            end

            reset_zoom_pan(app)
            for k=1:2
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
                click_buffer=(max(app.FigXlimLive.XLim)-min(app.FigXlimLive.XLim))*0.01; %ATH Settingspanel
                xVertLineCoord(1)=xVertLineCoord(1);
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);
                    break;
                elseif get(app.hVerticalLines(1),'Value')<abs(event.IntersectionPoint(1)) & get(app.hVerticalLines(2),'Value')>abs(event.IntersectionPoint(1))
                    app.hLineToDrag = app.hVerticalLines(1:2);
                    break;

                end

            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)

            if ~isempty(app.hLineToDrag)
                % Get the current point in the figure
                currentPoint = app.FigXlimLive.CurrentPoint;
                x = currentPoint(1, 1);
                xlim = app.FigXlimLive.XLim;
                inside_FigXlimLive = x >= xlim(1) && x <= xlim(2);

                if inside_FigXlimLive
                    if numel(app.hLineToDrag) == 2
                        % Get the current positions of the vertical lines
                        xposlines = [get(app.hVerticalLines(1), 'Value'), get(app.hVerticalLines(2), 'Value')];
                        difference = range(xposlines) / 2;

                        % Calculate new positions ensuring they stay within the signal limits
                        x1 = max(min(x - difference, max(1:length(table2array(app.base.project.session.ms_data(:, 1))))), min(1:length(table2array(app.base.project.session.ms_data(:, 1)))));
                        x2 = max(min(x + difference, max(1:length(table2array(app.base.project.session.ms_data(:, 1))))), min(1:length(table2array(app.base.project.session.ms_data(:, 1)))));
                        set(app.hLineToDrag(1), 'Value', x1);
                        set(app.hLineToDrag(2), 'Value', x2);

                        % Update the EditField values
                        xposlines = [get(app.hVerticalLines(1), 'Value'), get(app.hVerticalLines(2), 'Value')];
                        app.EditField_xlimMin.Value = min(xposlines);
                        app.EditField_xlimMax.Value = max(xposlines);
                    else
                        % Ensure the line stays within the signal limits
                        x = max(min(x, max(1:length(table2array(app.base.project.session.ms_data(:, 1))))), min(1:length(table2array(app.base.project.session.ms_data(:, 1)))));
                        set(app.hLineToDrag, 'Value', x);

                        % Update the EditField values
                        xposlines = [get(app.hVerticalLines(1), 'Value'), get(app.hVerticalLines(2), 'Value')];
                        app.EditField_xlimMin.Value = min(xposlines);
                        app.EditField_xlimMax.Value = max(xposlines);
                    end
                else
                    % If the point is outside the figure, stop dragging
                    UIFigureWindowButtonUp(app)
                end
            end

        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~isempty(app.hLineToDrag)
                % Clear the reference to the line being dragged
                app.hLineToDrag = [];

                % Update the x-limits based on the EditField values
                app.xlimits = [app.EditField_xlimMin.Value, app.EditField_xlimMax.Value];

                % Set the vertical lines to the new x-limits
                set(app.hVerticalLines(1), 'Value', app.EditField_xlimMin.Value)
                set(app.hVerticalLines(2), 'Value', app.EditField_xlimMax.Value)

                % Update the zoom plot and the live x-limits figure
                UpdateZoomedPlot(app)
                update_FigXlimLive(app)
            end
        end

        % Value changed function: EditField_xlimMax, EditField_xlimMin
        function EditField_LiveXMinMaxValueChanged(app, event)


            if not(isfield(app.base.project.session, 'ms_data'))
                return
            end

            % Get the new values from the EditFields
            ValueMin = app.EditField_xlimMin.Value;
            ValueMax = app.EditField_xlimMax.Value;

            % Check if the minimum and maximum values are equal
            if ValueMin == ValueMax
                0; % No action needed if they are equal
            end

            % Ensure the minimum value is less than the maximum value
            if ValueMin < ValueMax
                try
                    % Set the vertical lines to the new values
                    set(app.hVerticalLines(1), 'Value', ValueMin)
                    set(app.hVerticalLines(2), 'Value', ValueMax)
                catch
                    % If setting the values fails, create new vertical lines
                    app.hVerticalLines(1:2) = [
                        xline(app.FigXlimLive, app.EditField_xlimMin.Value, 'Color', 'red', "LineWidth", 3), ...
                        xline(app.FigXlimLive, app.EditField_xlimMax.Value, 'Color', 'blue', "LineWidth", 3)
                        ];
                end
            end

            % Calculate the new x-limits for the figure
            xlimval = [
                min([app.EditField_xlimMin.Value min(1:length(table2array(app.base.project.session.ms_data(:, 1))))]), ...
                max([app.EditField_xlimMax.Value max(1:length(table2array(app.base.project.session.ms_data(:, 1))))])
                ];
            % Set the x-limits of the figure
            xlim(app.FigXlimLive, xlimval)

            % Update the zoom plot
            update_FigXlimLive(app)
            UpdateZoomedPlot(app)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)


            if not(isfield(app.base.project.session, 'ms_data'))
                return
            end

                % Here they get filled for the first time
                app.EditField_xlimMax.Value = length(table2array(app.base.project.session.ms_data(:, 1)));
                app.EditField_xlimMin.Value = 1;

                xlimval = [app.EditField_xlimMin.Value, app.EditField_xlimMax.Value];

            % Store the x-limits in the app properties
            app.xlimits = xlimval;

            % Update the zoom plot and the live x-limits figure
            update_FigXlimLive(app)

            % set the selected_analytes of the ZoomedPlot_Legend to empty so that all
            % analytes are plotted
            app.selected_analytes = [];
            UpdateZoomedPlot(app)

        end

        % Button pushed function: xlimMin_m10
        function xlimMin_m10ButtonPushed(app, event)

            if not(isfield(app.base.project.session, 'ms_data'))
                return
            end
            % Calculate the new value
            newValue = app.EditField_xlimMin.Value - 100;
            % Ensure the new value does not go below the minimum x limit
            app.EditField_xlimMin.Value = max(newValue, 1);
            % Update the plot
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_m1
        function xlimMin_m1ButtonPushed(app, event)

            if not(isfield(app.base.project.session, 'ms_data'))
                return
            end
            % Calculate the new value
            newValue = app.EditField_xlimMin.Value - 10;
            % Ensure the new value does not go below the minimum x limit
            app.EditField_xlimMin.Value = max(newValue, 1);
            % Update the plot
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_p1
        function xlimMin_p1ButtonPushed(app, event)


            if not(isfield(app.base.project.session, 'ms_data'))
                return
            end
            % Calculate the new value
            newValue = app.EditField_xlimMin.Value + 10;
            % Ensure the new value does not go above the maximum x limit
            app.EditField_xlimMin.Value = min(newValue, length(table2array(app.base.project.session.ms_data(:, 1))));
            % Update the plot
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_m1
        function xlimMax_m1ButtonPushed(app, event)


            if not(isfield(app.base.project.session, 'ms_data'))
                return
            end
            % Calculate the new value
            newValue = app.EditField_xlimMax.Value - 10;
            % Ensure the new value does not go below the minimum x limit
            app.EditField_xlimMax.Value = max(newValue, 1);
            % Update the plot
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p1
        function xlimMax_p1ButtonPushed(app, event)


            if not(isfield(app.base.project.session, 'ms_data'))
                return
            end
            % Calculate the new value
            newValue = app.EditField_xlimMax.Value + 10;
            % Ensure the new value does not go above the maximum x limit
            app.EditField_xlimMax.Value = min(newValue, length(table2array(app.base.project.session.ms_data(:, 1))));
            % Update the plot
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p10
        function xlimMax_p10ButtonPushed(app, event)


            if not(isfield(app.base.project.session, 'ms_data'))
                return
            end
            % Calculate the new value
            newValue = app.EditField_xlimMax.Value + 100;
            % Ensure the new value does not go above the maximum x limit
            app.EditField_xlimMax.Value = min(newValue, length(table2array(app.base.project.session.ms_data(:, 1))));
            % Update the plot
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: GetMeasurementFileButton
        function GetMeasurementFileButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;
            
            try
                %% Agilent 7900 measurement file import

                [filename, pathname] = uigetfile('*.*', 'Select a file');
                app.base.project.session.measurementpath = fullfile(pathname, filename);

                app.MeasurementFileEditField.Value = app.base.project.session.measurementpath;

                if not(filename==0)
                    % this function is also in the API wrapper measurement_logfile_import_public
                    settings.massspec.Value = app.ChooseMassSpecDropDown.Value;
                    [~, app.base.project.session.ms_data, ~] = read_mass_spec_file_public(app, app.base.project.session.measurementpath, settings);
                    app.base.project.session.ms_data_raw = app.base.project.session.ms_data;
                    app.base.project.session.analytes = app.base.project.session.ms_data.Properties.VariableNames(2:end)';

                    app.MeasurementLamp.Color = 'g';
                end


                %% Plot the interactive FigXlimLive for the first time

                % get the minimum and maximum limits for the FigXlimLive Plot
                xlimval=[min([app.EditField_xlimMin.Value; table2array(app.base.project.session.ms_data(:, 1))])...
                    max([app.EditField_xlimMax.Value; table2array(app.base.project.session.ms_data(:, 1))])];

                % Here they get filled for the first time
                app.EditField_xlimMax.Value=max(xlimval);
                app.EditField_xlimMin.Value=min(xlimval);

                app.xlimits=xlimval;

                %plot the FixXlimLive
                update_FigXlimLive(app)

                %% Plot the Zoomed Plot for the first time
                %populate the ZoomedPlot_Legend
                update_ZoomedPlot_Legend_fun(app)
                UpdateZoomedPlot(app)

            catch ME
                DisplayError(ME,'Get Measurement File Button')

                message = "Measurement file could not be loaded";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

            end

        end

        % Button pushed function: GetLogFileButton, GetLogFileButton_Single
        function GetLogFileButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            try
                % if the button is pushed it asks for a file with .log ending
                [filename, pathname] = uigetfile('*.*', 'Select a log file');
                app.base.project.session.logfilepath = fullfile(pathname, filename);

                app.LogFileEditField.Value = app.base.project.session.logfilepath;

                if not(filename==0)

                    settings.laser.Value = app.ChooseLaserDropDown.Value;
                    % erster output is settings, wird gelöscht weil nicht
                    % gebraucht
                    % this function is also in the API wrapper measurement_logfile_import_public
                    %[~, app.base.project.session.spotname, ~] = read_logfile_public(app, app.base.project.session.logfilepath, settings);
                    [~, app.base.project.session.spotname, app.base.project.session.spot.size, app.base.project.session.spot.rep_rate, app.base.project.session.spot.fluence, app.base.project.session.spot.laser_start_times, app.base.project.session.spot.laser_stop_times, ~] = read_logfile_public(app, app.base.project.session.logfilepath, settings);
                end

                app.LogfileLamp.Color = 'g';
                app.ShowLogfileInformationsButton.Enable = 'on';

            catch ME
                DisplayError(ME,'read_resonetics_verbose_log')

                message = "Logfile could not be loaded";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

            end

        end

        % Button pushed function: xlimMin_p10
        function xlimMin_p10ButtonPushed(app, event)


            if not(isfield(app.base.project.session, 'ms_data'))
                return
            end
            % Calculate the new value
            newValue = app.EditField_xlimMin.Value + 100;
            % Ensure the new value does not go above the maximum x limit
            app.EditField_xlimMin.Value = min(newValue, length(table2array(app.base.project.session.ms_data(:, 1))));
            % Update the plot
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_m10
        function xlimMax_m10ButtonPushed(app, event)

            if not(isfield(app.base.project.session, 'ms_data'))
                return
            end
            % Calculate the new value
            newValue = app.EditField_xlimMax.Value - 100;
            % Ensure the new value does not go above the maximum x limit
            app.EditField_xlimMax.Value = min(newValue, length(table2array(app.base.project.session.ms_data(:, 1))));
            % Update the plot
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Value changed function: ZoomedPlot_Legend
        function ZoomedPlot_LegendValueChanged(app, event)
            % if the Legend Value changes the Zoomed Plot gets updated

            app.selected_analytes = app.ZoomedPlot_Legend.Value;

            UpdateZoomedPlot(app)
        end

        % Button pushed function: DetectSignalsButton
        function DetectSignalsButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;
            
            %% this should be done in a wrapper that is a public function, see above
            %writes a message if no fullsignal and logfile has been loaded has been selected but the
            %buttton is pressed

            if ~isstruct(app.base.project) || ~isstruct(app.base.project.session) || isempty(app.base.project.session)
                message = "Load a measurement file and a logfile";
                uialert(app.UIFigure, message, "Warning", "Icon", "warning");
                return
            end

            if ~isfield(app.base.project.session, 'ms_data') && ~isfield(app.base.project.session, 'spotname')
                message = "Load a measurement file and a logfile";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            elseif isfield(app.base.project.session, 'ms_data') && ~isfield(app.base.project.session, 'spotname')

                message = "Load a logfile";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            elseif ~isfield(app.base.project.session, 'ms_data') && isfield(app.base.project.session, 'spotname')

                message = "Load a measurement file";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return

            else

                % Try first detection method
                try
                    [app.base.project.session.spot.signal_starts, ...
                        app.base.project.session.spot.signal_ends] = ...
                        detect_signals(app, app.base.project.session.ms_data, ...
                        numel(app.base.project.session.spotname));

                    app.DetectSignalsLamp.Color = 'g';
                    UpdateZoomedPlot(app);
                    return; % Exit if first method succeeds

                catch ME1
                    % If first method fails, try second method
                    try
                        [app.base.project.session.spot.signal_starts, ...
                            app.base.project.session.spot.signal_ends] = ...
                            detect_signals_via_time(app, app.base.project.session.ms_data, ...
                            app.base.project.session.spot.laser_start_times, app.base.project.session.spot.laser_stop_times);

                        app.DetectSignalsLamp.Color = 'g';
                        UpdateZoomedPlot(app);
                        return;

                    catch ME2
                        % If both methods fail, display error
                        DisplayError(ME1, 'detect_signals');
                        DisplayError(ME2, 'detect_signals_via_time');

                        message = "Signals could not be detected using either method";
                        uialert(app.UIFigure, message, "Warning", "Icon", "warning");
                        app.DetectSignalsLamp.Color = [0.64, 0.08, 0.18];
                    end
                end

            end


        end

        % Value changed function: PlotSignalRangesCheckBox
        function PlotSignalRangesCheckBoxValueChanged(app, event)
            UpdateZoomedPlot(app);
        end

        % Value changed function: ShowSpotnamesCheckBox
        function ShowSpotnamesCheckBoxValueChanged(app, event)
            UpdateZoomedPlot(app);
        end

        % Button pushed function: GetDwellTimesButton
        function GetDwellTimesButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;
            
            try

                % if the button is pushed it asks for a file with .xml ending
                [filename, pathname] = uigetfile('*.*', 'Select a file');
                app.base.project.session.dwelltimepath = fullfile(pathname, filename);

                app.DwellTimeEditField.Value = app.base.project.session.dwelltimepath;

                % make the import here
                if not(filename==0)
                    % reads in the AcqMethod.xml file to get the element names, dwelltimes
                    % this function is also in the API wrapper measurement_logfile_import_public
                    settings.dwelltime.Value = app.ChooseDwellTimeDropDown.Value;
                    [~, app.base.project.session.dwelltimes, ~] = read_dwelltime_file_public(app, app.base.project.session.dwelltimepath, settings);

                    app.DwellTimeLamp.Color = 'g';
                    app.ShowDwellTimesButton.Enable = 'on';
                end

                % make different checks here

                % check if the analytes from the measruement file and the
                % dwelltime file are the same
                % if isfield(app.base.project.session, 'analytes') && isfield(app.base.project.session, 'dwelltimeanalytes')
                %     if ~isequal(sort(app.base.project.session.analytes), sort(app.base.project.session.dwelltimeanalytes))
                %         message = "Analytes from measurement file and dwelltime file are different, restart the session";
                %         uialert(app.UIFigure, message, "Warning", "Icon", "warning");
                %         delete the fields
                %         app.base.project.session = rmfield(app.base.project.session, 'dwelltimeanalytes');
                %         app.base.project.session = rmfield(app.base.project.session, 'dwelltimes');
                %     end
                % end

            catch ME
                DisplayError(ME,'get_agilent_acqmethod_dwelltimes')

                message = "Dwelltimes could not be loaded";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

            end

        end

        % Button pushed function: ShowDwellTimesButton
        function ShowDwellTimesButtonPushed(app, event)

            % Create a new figure window
            fig = uifigure('Name', 'Dwell Times', 'Position', [100 100 1200 100]);

            % Create a uitable
            uit = uitable(fig);
            uit.Position = [20 20 1160 70];

            % Get the dwell times data
            dwellData = app.base.project.session.dwelltimes;

            % Set the data and column names
            uit.Data = table2array(dwellData);
            uit.ColumnName = dwellData.Properties.VariableNames;

            % Make table editable
            uit.ColumnEditable = true(1, width(dwellData));

            % Add cell edit callback to validate and update values
            uit.CellEditCallback = @(src, event) updateDwellTime(app, src, event);

            % Adjust column widths
            uit.ColumnWidth = repmat({50}, 1, width(dwellData));


        end

        % Button pushed function: ShowLogfileInformationsButton
        function ShowLogfileInformationsButtonPushed(app, event)


            % Create a new figure with specified size
            fig = uifigure('Name', 'Logfile Informations', 'Position', [200, 200, 400, 600]); % Adjust the size as needed

            % Create a table in the new figure
            uit = uitable(fig);

            % Set the data for the table
            tabledata = table(app.base.project.session.spotname, ...
                'VariableNames', {'Spotname'});

            % Assign the data to the table
            uit.Data = tabledata;

            % Set the position and size of the table within the figure
            uit.Position = [20 20 360 560]; % Adjust the position and size as needed

            % Make the DwellTimes column editable
            uit.ColumnEditable = [true, true];

            % Center the column entries
            style = uistyle('HorizontalAlignment', 'center');
            addStyle(uit, style);

            % Add CellEditCallback to update the data structure
            uit.CellEditCallback = @(src, event) updateLogfileInformations(app, src, event);


        end

        % Menu selected function: SaveSessiontoBaseandExitMenu
        function SaveSessiontoBaseandExitMenuSelected(app, event)
            if isempty(app.base) || isempty(app.base.project)
                delete(app)
                return
            end

            settings.DIQES_GUI_update_state = true;

            [~,~] =   save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

            delete(app)
        end

        % Value changed function: LogScaleCheckBox
        function LogScaleCheckBoxValueChanged(app, event)
            value = app.LogScaleCheckBox.Value;

            UpdateZoomedPlot(app)
        end

        % Button pushed function: ProceedinInclusionModeButton, 
        % ...and 1 other component
        function ProceedinInclusionModeButtonPushed(app, event)

            try
                app.base.project.session.meta.reductiontype = 'Inclusion';

                switch event.Source.Parent(1).Parent(1).Title
                    case 'Measurement Files + Laser Logfile'
                        app.base.project.session.meta.datatype = 'Continuous';
                    case 'Single Signals (Shot-by-Shot)'
                        app.base.project.session.meta.datatype = 'Single';
                end

                app.base.project.session.meta.module_status.import = true;

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to proceed and save the data?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Save","Don't save","Cancel"], ...
                    "DefaultOption",1,"CancelOption",3);

                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        delete(app)
                    case "Don't save"
                        Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                DisplayError(ME,'Proceed in inclusion Mode Button failed')
                message = "Proceeding in inclusion mode failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end


        end

        % Button pushed function: GetSingleSpotFileButton
        function GetSingleSpotFileButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            try
                %% Agilent 7900 single spots file import

                [filenames, pathname] = uigetfile('*.*', 'Select files', 'MultiSelect', 'on');

                if isequal(filenames, 0)
                    return; % User canceled the file selection
                end

                if ischar(filenames)
                    filenames = {filenames}; % Ensure filenames is a cell array
                end

                new_measurementpaths = fullfile(pathname, filenames)';

                % this function is also in the API wrapper measurement_logfile_import_public
                settings.massspec.Value = app.ChooseMassSpecSSDropDown.Value;

                if ~isfield(app.base, 'project') || ~isfield(app.base.project, 'session') || ~isfield(app.base.project.session, 'spot')
                    current_length = 0;
                elseif isfield(app.base.project.session, 'spot') && isfield(app.base.project.session.spot, 'ms_data_single_spot') && ~isempty(app.base.project.session.spot.ms_data_single_spot)
                    current_length = length(app.base.project.session.spot.ms_data_single_spot);
                end

                all_analytes = {};

                for i = 1:length(filenames)
                    measurementpath = new_measurementpaths{i};
                    filename = filenames{i};

                    [~, ms_data_single_spot, ~ , spotname, time] = read_single_spot_files_public(app, measurementpath, settings);

                    % Collect analytes from the current file
                    current_analytes = ms_data_single_spot.Properties.VariableNames(2:end)';
                    if isempty(all_analytes)
                        all_analytes = current_analytes;
                    else
                        if ~isequal(sort(all_analytes), sort(current_analytes))
                            message = "Only load files that contain the same analytes!";
                            uialert(app.UIFigure, message, "Warning", "Icon", "warning");
                            return;
                        end
                    end

                    % Check if there are already analytes in the data structure
                    if isfield(app.base, 'project') && isfield(app.base.project.session, 'analytes') && ~isempty(app.base.project.session.analytes)
                        if ~isequal(sort(app.base.project.session.analytes), sort(all_analytes))
                            message = "Only load files that contain the same analytes!";
                            uialert(app.UIFigure, message, "Warning", "Icon", "warning");
                            return;
                        end
                    end

                    app.base.project.session.analytes = current_analytes;

                    % First make the check and only then load them in
                    app.base.project.session.spot.ms_data_single_spot{current_length + i, 1} = ms_data_single_spot;
                    app.base.project.session.spot.ms_data_single_spot_raw{current_length + i, 1} = ms_data_single_spot;
                    app.base.project.session.measurementpath{current_length + i, 1} = char(measurementpath);
                    app.base.project.session.spot.acquisition_time{current_length + i, 1} = time;
                    app.base.project.session.spotname{current_length + i, 1} = char(spotname);

                    % signal detection
                    [signal_starts, signal_ends] = detect_single_spot_signal_starts_ends(app, ms_data_single_spot);
                    app.base.project.session.spot.signal_starts(current_length + i, 1) = signal_starts;
                    app.base.project.session.spot.signal_ends(current_length + i, 1) = signal_ends;

                end

                update_Imp_Mod_GUI_single_spot_Tab(app)

            catch ME
                DisplayError(ME,'Get Single Spot Files')

                message = "Single spot files could not be loaded";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

            end
        end

        % Value changed function: SingleSpotsListBox
        function SingleSpotsListBoxValueChanged(app, event)
            app.selected_spot = app.SingleSpotsListBox.Value;


            update_Imp_Mod_GUI_single_spot_Tab(app)

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %check if this is the first selected_spot, if yes and you
            %press it jumps to the first signal, otherwise it just goes
            %one signal forward
            if app.selected_spot == numel(app.SingleSpotsListBox.Items)

                app.selected_spot = 1;
                app.SingleSpotsListBox.Value = 1;
            else
                app.selected_spot=app.selected_spot + 1;
                app.SingleSpotsListBox.Value=app.SingleSpotsListBox.Value + 1;
            end

            %update the element list and the plot
            update_Imp_Mod_GUI_single_spot_Tab(app)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            %check if this is the first selected_spot, if yes and you
            %press it jumps to the first signal, otherwise it just goes
            %one signal forward
            if app.selected_spot == 1

                app.selected_spot = numel(app.SingleSpotsListBox.Items);
                app.SingleSpotsListBox.Value = numel(app.SingleSpotsListBox.Items);
            else
                app.selected_spot = app.selected_spot - 1;
                app.SingleSpotsListBox.Value=app.SingleSpotsListBox.Value - 1;
            end

            %update the element list and the plot
            update_Imp_Mod_GUI_single_spot_Tab(app)
        end

        % Button pushed function: GetDwelltimesButton
        function GetDwelltimesButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;
            
            try

                % Att what fileending is asked for depends on the selection
                % of the dropdown
                % if the button is pushed it asks for a file with .xml ending
                [filename, pathname] = uigetfile('*.*', 'Select a file');
                app.base.project.session.dwelltimepath = fullfile(pathname, filename);

                % make the import here
                if not(filename==0)
                    % reads in the AcqMethod.xml file to get the element names, dwelltimes
                    % this function is also in the API wrapper measurement_logfile_import_public
                    settings.dwelltime.Value = app.ChooseDwellTimeDropDown_2.Value;
                    [~, app.base.project.session.dwelltimes, ~] = read_dwelltime_file_public(app, app.base.project.session.dwelltimepath, settings);

                end

                % make different checks here

                % check if the analytes from the measruement file and the
                % dwelltime file are the same
                if isfield(app.base.project.session, 'analytes') && isfield(app.base.project.session, 'dwelltimeanalytes')
                    if ~isequal(sort(app.base.project.session.analytes), sort(app.base.project.session.dwelltimeanalytes))
                        message = "Analytes from measurement file and dwelltime file are different, restart the session";
                        uialert(app.UIFigure, message, "Warning", "Icon", "warning");
                        % delete the fields
                        %app.base.project.session = rmfield(app.base.project.session, 'dwelltimeanalytes');
                        %app.base.project.session = rmfield(app.base.project.session, 'dwelltimes');
                    end
                end

            catch ME
                DisplayError(ME,'get_agilent_acqmethod_dwelltimes')

                message = "Dwelltimes could not be loaded";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

            end


        end

        % Button pushed function: ProceedinMatrixModeButton, 
        % ...and 1 other component
        function ProceedinMatrixModeButtonPushed(app, event)
            try
                app.base.project.session.meta.reductiontype = 'Matrix';

                switch event.Source.Parent(1).Parent(1).Title
                    case 'Measurement Files + Laser Logfile'
                        app.base.project.session.meta.datatype = 'Continuous';
                    case 'Single Signals (Shot-by-Shot)'
                        app.base.project.session.meta.datatype = 'Single';
                end

                app.base.project.session.meta.module_status.import = true;

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to proceed and save the data?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Save","Don't save","Cancel"], ...
                    "DefaultOption",1,"CancelOption",3);
                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        delete(app)
                    case "Don't save"
                        Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME

                DisplayError(ME,'Proceed in Matrix Mode Button failed')
                message = "Proceeding in matrix mode failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           %%% UIconfirm
           % ask user if he wants to
           % Save (save), No Save (delete(app)), Cancel (return)
            delete(app)
            
        end

        % Callback function
        function ImportButtonPushed(app, event)
            try
                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to proceed and save the data?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Save","Don't save","Cancel"], ...
                    "DefaultOption",1,"CancelOption",3);

                switch selection
                    case 'Save'

                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case "Don't save"

                        switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case 'Cancel'

                        return
                end

            catch ME
                return
            end

        end

        % Callback function: ChooseLaserDropDown, ChooseLaserDropDown
        function ChooseLaserDropDownValueChanged(app, event)

            app.ChooseLaserDropDown.Value = event.Source.Value;
            app.LogFileDropDown_Single.Value = event.Source.Value;

        end

        % Value changed function: LogFileDropDown_Single
        function LogFileDropDown_SingleValueChanged(app, event)
            app.LogFileDropDown_Single.Value = event.Source.Value;
            app.ChooseLaserDropDown.Value = event.Source.Value;

            
        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ...and 3 other components
        function IntervalButtonPushed(app, event)
            try
                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'

                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case "Don't save"

                        switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case 'Cancel'

                        return
                end
            catch ME
                DisplayError(ME,'Proceed to other module failed')
                message = "Proceeding to other module failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end
        end

        % Value changed function: globalsignalindentEditField
        function globalsignalindentEditFieldValueChanged(app, event)
            app.global_signal_indent = app.globalsignalindentEditField.Value;
            
            DetectSignalsButtonPushed(app, event)

        end

        % Button pushed function: ImportSILLSprojectButton
        function ImportSILLSprojectButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;


            % [filename, pathname] = uigetfile('*.*', 'Select a file');
            % app.base.project.session.measurementpath = fullfile(pathname, filename);
            app.base.project.session.measurementpath = 'C:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\ICP-Base\LAICPMS Data\Inclusion Analysis\23.01.2024\Alm06-142 Flincs 1850 Cl\Alm06-142 Flincs GSD.mat';

            app.MeasurementFileEditField.Value = app.base.project.session.measurementpath;

           % if not(filename==0)
                % this function is also in the API wrapper measurement_logfile_import_public

                [~, ms_data_single_spot , ~, spotname, time] = read_SILLS_projects_public(app, app.base.project.session.measurementpath, settings);


                app.base.project.session.spot.ms_data_single_spot = ms_data_single_spot;
                app.base.project.session.spotname = spotname;
                app.base.project.session.spot.acquisition_time = time;
                app.base.project.session.spot.ms_data_single_spot_raw = app.base.project.session.spot.ms_data_single_spot;


           % end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SaveSessiontoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SaveSessiontoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessiontoBaseandExitMenuSelected, true);
            app.SaveSessiontoBaseandExitMenu.Text = 'Save Session to Base and Exit';
            app.SessionMenu = uimenu(app.UIFigure);
            app.SessionMenu.Text = 'Session';
            app.SaveSessionMenu = uimenu(app.SessionMenu);
            app.SaveSessionMenu.Text = 'Save Session';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.MeasurementFilesLaserLogfileTab = uitab(app.TabGroup);
            app.MeasurementFilesLaserLogfileTab.Title = 'Measurement Files + Laser Logfile';
            app.GridLayout = uigridlayout(app.MeasurementFilesLaserLogfileTab);
            app.ZoomedPlot = uiaxes(app.GridLayout);
            xlabel(app.ZoomedPlot, 'Sweeps')
            ylabel(app.ZoomedPlot, 'Intensity [cps]')
            zlabel(app.ZoomedPlot, 'Z')
            app.FigXlimLive = uiaxes(app.GridLayout);
            app.FigXlimLive.XTick = [];
            app.FigXlimLive.YTick = [];
            app.FigXlimLive.ButtonDownFcn = createCallbackFcn(app, @FigXlimLiveButtonDown, true);
            app.MeasurementFileEditField = uieditfield(app.GridLayout, 'text');
            app.MeasurementFileEditField.Editable = 'off';
            app.LogFileEditField = uieditfield(app.GridLayout, 'text');
            app.LogFileEditField.Editable = 'off';
            app.GetMeasurementFileButton = uibutton(app.GridLayout, 'push');
            app.GetMeasurementFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetMeasurementFileButtonPushed, true);
            app.GetMeasurementFileButton.Text = 'Get Measurement File';
            app.GetLogFileButton = uibutton(app.GridLayout, 'push');
            app.GetLogFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetLogFileButtonPushed, true);
            app.GetLogFileButton.Text = 'Get Log File';
            app.ChooseMassSpecDropDown = uidropdown(app.GridLayout);
            app.ChooseMassSpecDropDown.Items = {'Agilent 7900'};
            app.ChooseMassSpecDropDown.Value = 'Agilent 7900';
            app.ChooseLaserDropDown = uidropdown(app.GridLayout);
            app.ChooseLaserDropDown.Items = {'Resonetics Verbose Log (.log)', 'Resonetics Log (.csv)'};
            app.ChooseLaserDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseLaserDropDownValueChanged, true);
            app.ChooseLaserDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseLaserDropDownValueChanged, true);
            app.ChooseLaserDropDown.Value = 'Resonetics Verbose Log (.log)';
            app.ProceedinInclusionModeButton = uibutton(app.GridLayout, 'push');
            app.ProceedinInclusionModeButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedinInclusionModeButtonPushed, true);
            app.ProceedinInclusionModeButton.Text = 'Proceed in Inclusion Mode';
            app.DwellTimeEditField = uieditfield(app.GridLayout, 'text');
            app.DwellTimeEditField.Editable = 'off';
            app.GetDwellTimesButton = uibutton(app.GridLayout, 'push');
            app.GetDwellTimesButton.ButtonPushedFcn = createCallbackFcn(app, @GetDwellTimesButtonPushed, true);
            app.GetDwellTimesButton.Text = 'Get Dwell Times';
            app.ChooseDwellTimeDropDown = uidropdown(app.GridLayout);
            app.ChooseDwellTimeDropDown.Items = {'Agilent AcqMethod.xml'};
            app.ChooseDwellTimeDropDown.Value = 'Agilent AcqMethod.xml';
            app.LogfileLamp = uilamp(app.GridLayout);
            app.DwellTimeLamp = uilamp(app.GridLayout);
            app.ZoomedPlot_Legend = uilistbox(app.GridLayout);
            app.ZoomedPlot_Legend.Items = {};
            app.ZoomedPlot_Legend.Multiselect = 'on';
            app.ZoomedPlot_Legend.ValueChangedFcn = createCallbackFcn(app, @ZoomedPlot_LegendValueChanged, true);
            app.ZoomedPlot_Legend.Value = {};
            app.xlimMin_m10 = uibutton(app.GridLayout, 'push');
            app.xlimMin_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m10ButtonPushed, true);
            app.xlimMin_m10.Text = '-100';
            app.xlimMin_m1 = uibutton(app.GridLayout, 'push');
            app.xlimMin_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m1ButtonPushed, true);
            app.xlimMin_m1.Text = '-10';
            app.EditField_xlimMin = uieditfield(app.GridLayout, 'numeric');
            app.EditField_xlimMin.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveXMinMaxValueChanged, true);
            app.xlimMin_p1 = uibutton(app.GridLayout, 'push');
            app.xlimMin_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p1ButtonPushed, true);
            app.xlimMin_p1.Text = '+10';
            app.xlimMin_p10 = uibutton(app.GridLayout, 'push');
            app.xlimMin_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p10ButtonPushed, true);
            app.xlimMin_p10.Text = '+100';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.xlimMax_m10 = uibutton(app.GridLayout, 'push');
            app.xlimMax_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m10ButtonPushed, true);
            app.xlimMax_m10.Text = '-100';
            app.xlimMax_m1 = uibutton(app.GridLayout, 'push');
            app.xlimMax_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m1ButtonPushed, true);
            app.xlimMax_m1.Text = '-10';
            app.EditField_xlimMax = uieditfield(app.GridLayout, 'numeric');
            app.EditField_xlimMax.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveXMinMaxValueChanged, true);
            app.xlimMax_p1 = uibutton(app.GridLayout, 'push');
            app.xlimMax_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p1ButtonPushed, true);
            app.xlimMax_p1.Text = '+10';
            app.xlimMax_p10 = uibutton(app.GridLayout, 'push');
            app.xlimMax_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p10ButtonPushed, true);
            app.xlimMax_p10.Text = '+100';
            app.MeasurementLamp = uilamp(app.GridLayout);
            app.DetectSignalsButton = uibutton(app.GridLayout, 'push');
            app.DetectSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectSignalsButtonPushed, true);
            app.DetectSignalsButton.Text = 'Detect Signals';
            app.PlotSignalRangesCheckBox = uicheckbox(app.GridLayout);
            app.PlotSignalRangesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotSignalRangesCheckBoxValueChanged, true);
            app.PlotSignalRangesCheckBox.Text = 'Plot Signal Ranges';
            app.PlotSignalRangesCheckBox.Value = true;
            app.DetectSignalsLamp = uilamp(app.GridLayout);
            app.ShowDwellTimesButton = uibutton(app.GridLayout, 'push');
            app.ShowDwellTimesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDwellTimesButtonPushed, true);
            app.ShowDwellTimesButton.Enable = 'off';
            app.ShowDwellTimesButton.Text = 'Show Dwell Times';
            app.ShowLogfileInformationsButton = uibutton(app.GridLayout, 'push');
            app.ShowLogfileInformationsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLogfileInformationsButtonPushed, true);
            app.ShowLogfileInformationsButton.Enable = 'off';
            app.ShowLogfileInformationsButton.Text = 'Show Logfile Informations';
            app.ShowSpotnamesCheckBox = uicheckbox(app.GridLayout);
            app.ShowSpotnamesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSpotnamesCheckBoxValueChanged, true);
            app.ShowSpotnamesCheckBox.Text = 'Show Spotnames';
            app.LogScaleCheckBox = uicheckbox(app.GridLayout);
            app.LogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogScaleCheckBoxValueChanged, true);
            app.LogScaleCheckBox.Text = 'Log-Scale';
            app.LogScaleCheckBox.Value = true;
            app.ProceedinMatrixModeButton = uibutton(app.GridLayout, 'push');
            app.ProceedinMatrixModeButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedinMatrixModeButtonPushed, true);
            app.ProceedinMatrixModeButton.Text = 'Proceed in Matrix Mode';
            app.globalsignalindentEditField = uieditfield(app.GridLayout, 'numeric');
            app.globalsignalindentEditField.ValueChangedFcn = createCallbackFcn(app, @globalsignalindentEditFieldValueChanged, true);
            app.SingleSignalsShotbyShotTab = uitab(app.TabGroup);
            app.SingleSignalsShotbyShotTab.Title = 'Single Signals (Shot-by-Shot)';
            app.GridLayout3 = uigridlayout(app.SingleSignalsShotbyShotTab);
            app.SingleSpotAxes = uiaxes(app.GridLayout3);
            title(app.SingleSpotAxes, 'Title')
            xlabel(app.SingleSpotAxes, 'Sweeps')
            ylabel(app.SingleSpotAxes, 'Intensity [cps]')
            zlabel(app.SingleSpotAxes, 'Z')
            app.GetSingleSpotFileButton = uibutton(app.GridLayout3, 'push');
            app.GetSingleSpotFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetSingleSpotFileButtonPushed, true);
            app.GetSingleSpotFileButton.Text = 'Get Single Spot File';
            app.SingleSpotsListBox = uilistbox(app.GridLayout3);
            app.SingleSpotsListBox.Items = {''};
            app.SingleSpotsListBox.ValueChangedFcn = createCallbackFcn(app, @SingleSpotsListBoxValueChanged, true);
            app.SingleSpotsListBox.Value = '';
            app.ChooseMassSpecSSDropDown = uidropdown(app.GridLayout3);
            app.ChooseMassSpecSSDropDown.Items = {'Agilent 7900', 'PerkinElmer', 'Thermo Fisher Element XR'};
            app.ChooseMassSpecSSDropDown.Value = 'Agilent 7900';
            app.GetDwelltimesButton = uibutton(app.GridLayout3, 'push');
            app.GetDwelltimesButton.ButtonPushedFcn = createCallbackFcn(app, @GetDwelltimesButtonPushed, true);
            app.GetDwelltimesButton.Text = 'Get Dwelltimes';
            app.ChooseDwellTimeDropDown_2 = uidropdown(app.GridLayout3);
            app.ChooseDwellTimeDropDown_2.Items = {'Agilent AcqMethod.xml', 'Thermo Fisher Element XR', '.xlsx'};
            app.ChooseDwellTimeDropDown_2.Value = 'Agilent AcqMethod.xml';
            app.NextButton = uibutton(app.GridLayout3, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.GridLayout3, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.ProceedinMatrixModeButton_Single = uibutton(app.GridLayout3, 'push');
            app.ProceedinMatrixModeButton_Single.ButtonPushedFcn = createCallbackFcn(app, @ProceedinMatrixModeButtonPushed, true);
            app.ProceedinMatrixModeButton_Single.Text = 'Proceed in Matrix Mode';
            app.GetLogFileButton_Single = uibutton(app.GridLayout3, 'push');
            app.GetLogFileButton_Single.ButtonPushedFcn = createCallbackFcn(app, @GetLogFileButtonPushed, true);
            app.GetLogFileButton_Single.Text = 'Get Log File';
            app.LogFileDropDown_Single = uidropdown(app.GridLayout3);
            app.LogFileDropDown_Single.Items = {'Resonetics Verbose Log (.log)', 'Resonetics Log (.csv)'};
            app.LogFileDropDown_Single.ValueChangedFcn = createCallbackFcn(app, @LogFileDropDown_SingleValueChanged, true);
            app.LogFileDropDown_Single.Value = 'Resonetics Verbose Log (.log)';
            app.ProceedinInclusionModeButton_Single = uibutton(app.GridLayout3, 'push');
            app.ProceedinInclusionModeButton_Single.ButtonPushedFcn = createCallbackFcn(app, @ProceedinInclusionModeButtonPushed, true);
            app.ProceedinInclusionModeButton_Single.Text = 'Proceed in Inclusion Mode';
            app.SILLSProjectTab = uitab(app.TabGroup);
            app.SILLSProjectTab.Title = 'SILLS Project';
            app.ImportSILLSprojectButton = uibutton(app.SILLSProjectTab, 'push');
            app.ImportSILLSprojectButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSILLSprojectButtonPushed, true);
            app.ImportSILLSprojectButton.Text = 'Import SILLS project';
            app.VisualSelectionTab = uitab(app.TabGroup);
            app.VisualSelectionTab.Title = 'Visual Selection';
            app.ProgressPanel = uipanel(app.GridLayout2);
            app.IntervalButton = uibutton(app.ProgressPanel, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @IntervalButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.ProgressPanel, 'push');
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.ProgressPanel, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @IntervalButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.ProgressPanel, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @IntervalButtonPushed, true);
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.ProgressPanel, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @IntervalButtonPushed, true);
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.ProgressPanel, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @IntervalButtonPushed, true);
            app.ResultsButton.Text = 'Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end